classdef ganshe1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        gansheUIFigure       matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        Tab_3                matlab.ui.container.Tab
        Panel_6              matlab.ui.container.Panel
        Label_10             matlab.ui.control.Label
        Image5               matlab.ui.control.Image
        Image4               matlab.ui.control.Image
        Label_9              matlab.ui.control.Label
        Image3               matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        Image                matlab.ui.control.Image
        TextArea             matlab.ui.control.TextArea
        Tab                  matlab.ui.container.Tab
        XmLabel              matlab.ui.control.Label
        m0Label              matlab.ui.control.Label
        YButton              matlab.ui.control.Button
        XButton              matlab.ui.control.Button
        Button_9             matlab.ui.control.Button
        Button_8             matlab.ui.control.Button
        Button_7             matlab.ui.control.Button
        Button_6             matlab.ui.control.Button
        Button_5             matlab.ui.control.Button
        Button_4             matlab.ui.control.Button
        Button               matlab.ui.control.Button
        Panel_3              matlab.ui.container.Panel
        yN2Spinner           matlab.ui.control.Spinner
        yN2SpinnerLabel      matlab.ui.control.Label
        xN1Spinner           matlab.ui.control.Spinner
        xN1SpinnerLabel      matlab.ui.control.Label
        ybmSpinner           matlab.ui.control.Spinner
        ybmSpinnerLabel      matlab.ui.control.Label
        xbmSpinner           matlab.ui.control.Spinner
        xbmSpinnerLabel      matlab.ui.control.Label
        yamSpinner           matlab.ui.control.Spinner
        yamSpinnerLabel      matlab.ui.control.Label
        xamSpinner           matlab.ui.control.Spinner
        xamSpinnerLabel      matlab.ui.control.Label
        Panel_2              matlab.ui.container.Panel
        ammEditField         matlab.ui.control.NumericEditField
        amLabel              matlab.ui.control.Label
        LmmEditField         matlab.ui.control.NumericEditField
        LmmEditFieldLabel    matlab.ui.control.Label
        CheckBox_2           matlab.ui.control.CheckBox
        h2mEditField         matlab.ui.control.NumericEditField
        h2mEditFieldLabel    matlab.ui.control.Label
        n2EditField          matlab.ui.control.NumericEditField
        n2EditFieldLabel     matlab.ui.control.Label
        DmEditField          matlab.ui.control.NumericEditField
        DmLabel              matlab.ui.control.Label
        h1mEditField         matlab.ui.control.NumericEditField
        h1mEditFieldLabel    matlab.ui.control.Label
        n1EditField          matlab.ui.control.NumericEditField
        n1EditFieldLabel     matlab.ui.control.Label
        CheckBox             matlab.ui.control.CheckBox
        dmmEditField         matlab.ui.control.NumericEditField
        dmmEditFieldLabel    matlab.ui.control.Label
        nmEditField          matlab.ui.control.NumericEditField
        nmEditFieldLabel     matlab.ui.control.Label
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
        Tab_2                matlab.ui.container.Tab
        Button_25            matlab.ui.control.Button
        Button_24            matlab.ui.control.Button
        Button_23            matlab.ui.control.Button
        Button_22            matlab.ui.control.Button
        Button_21            matlab.ui.control.Button
        Button_20            matlab.ui.control.Button
        Panel_5              matlab.ui.container.Panel
        yN2Spinner_2         matlab.ui.control.Spinner
        yN2Spinner_2Label    matlab.ui.control.Label
        xN1Spinner_2         matlab.ui.control.Spinner
        xN1Spinner_2Label    matlab.ui.control.Label
        ybmSpinner_2         matlab.ui.control.Spinner
        ybmSpinner_2Label    matlab.ui.control.Label
        xbmSpinner_2         matlab.ui.control.Spinner
        xbmSpinner_2Label    matlab.ui.control.Label
        yamSpinner_2         matlab.ui.control.Spinner
        yamSpinner_2Label    matlab.ui.control.Label
        xamSpinner_2         matlab.ui.control.Spinner
        xamSpinner_2Label    matlab.ui.control.Label
        Panel_4              matlab.ui.container.Panel
        Slider_8             matlab.ui.control.Slider
        Label_4              matlab.ui.control.Label
        Slider_2             matlab.ui.control.Slider
        Label_8              matlab.ui.control.Label
        nmEditField_7        matlab.ui.control.NumericEditField
        nmLabel_7            matlab.ui.control.Label
        Slider_3             matlab.ui.control.Slider
        Label_7              matlab.ui.control.Label
        nmEditField_6        matlab.ui.control.NumericEditField
        nmLabel_6            matlab.ui.control.Label
        Slider_7             matlab.ui.control.Slider
        Label_6              matlab.ui.control.Label
        nmEditField_5        matlab.ui.control.NumericEditField
        nmLabel_5            matlab.ui.control.Label
        Slider_5             matlab.ui.control.Slider
        Label_5              matlab.ui.control.Label
        nmEditField_4        matlab.ui.control.NumericEditField
        nmLabel_4            matlab.ui.control.Label
        Slider_6             matlab.ui.control.Slider
        Label_3              matlab.ui.control.Label
        nmEditField_3        matlab.ui.control.NumericEditField
        nmLabel_3            matlab.ui.control.Label
        Slider_4             matlab.ui.control.Slider
        Label_2              matlab.ui.control.Label
        nmEditField_8        matlab.ui.control.NumericEditField
        nmLabel_2            matlab.ui.control.Label
        Slider               matlab.ui.control.Slider
        Label                matlab.ui.control.Label
        nmEditField_2        matlab.ui.control.NumericEditField
        nmLabel              matlab.ui.control.Label
        DmEditField_2        matlab.ui.control.NumericEditField
        DmEditField_2Label   matlab.ui.control.Label
        dmmEditField_2       matlab.ui.control.NumericEditField
        dmmEditField_2Label  matlab.ui.control.Label
        Button_19            matlab.ui.control.Button
        UIAxes2_2            matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x;y;xa;xb;ya;yb;I;lambda;D;d;n1;n2;h1;h2;N1;N2;stripe_spacing;
        a;L;a1;a2;xa2;xb2;ya2;yb2;N12;N22;d2;D2;x2;y2;I_2;bright;
        lambda_values;intensity_ratios;% 公共变量
    end
    
    methods (Access = private)
        function getvalue(app)
            % tab1
            app.lambda=app.nmEditField.Value*1e-9;%读入波长
            app.d=app.dmmEditField.Value*1e-3;%读入孔距
            app.D=app.DmEditField.Value;%读入观察屏距离
            app.n1=app.n1EditField.Value;% 折射率1
            app.h1=app.h1mEditField.Value*1e-6;% 厚度1
            app.n2=app.n2EditField.Value;% 折射率2
            app.h2=app.h2mEditField.Value*1e-6;% 厚度2
            app.L=app.LmmEditField.Value*1e-3;% 光源与双缝间的垂直距离
            app.a=app.ammEditField.Value*1e-3;% 光源与双缝连线中点的垂线之间的距离

            app.xa=app.xamSpinner.Value; %最小的横坐标值
            app.xb=app.xbmSpinner.Value;%最大的横坐标值
            app.ya=app.yamSpinner.Value;%最小的纵坐标值
            app.yb=app.ybmSpinner.Value;%最大的纵坐标值
            app.N1=app.xN1Spinner.Value;%x方向等分份数
            app.N2=app.yN2Spinner.Value;%y方向等分份数

            k=2*pi/app.lambda;          %计算波数
            app.x=linspace(app.xa,app.xb,app.N1);   %x坐标
            app.y=linspace(app.ya,app.yb,app.N2);   %y坐标
            [app.x,app.y]=meshgrid(app.x,app.y);
            r1=sqrt((app.x-app.d/2).^2+app.y.^2+app.D^2);%光程1
            r2=sqrt((app.x+app.d/2).^2+app.y.^2+app.D^2);%光程2
            app.a1=sqrt(app.L^2+(app.d/2-app.a)^2);% 光源到某一孔的光程
            app.a2=sqrt(app.L^2+(app.d/2+app.a)^2);% 光源到另一个孔的光程
            I_0=1;  % 假设初始光强等于1
            % 引入玻璃片后的光强公式：
            delta_phi = k * (r2 - r1) + k*(app.a2-app.a1) + k * ((app.n2 - 1) * app.h2 - (app.n1 - 1) * app.h1);
            app.I = 4 * I_0 * cos(delta_phi / 2).^2; % 光强计算
            
        end
        
        function getvalue2(app)
            % tab2
            app.xa2=app.xamSpinner_2.Value;%最小的横坐标值
            app.xb2=app.xbmSpinner_2.Value;%最大的横坐标值
            app.ya2=app.yamSpinner_2.Value;%最小的纵坐标值
            app.yb2=app.ybmSpinner_2.Value;%最大的纵坐标值
            app.N12=app.xN1Spinner_2.Value;%x方向等分份数
            app.N22=app.yN2Spinner_2.Value;%y方向等分份数
            
            app.d2=app.dmmEditField_2.Value*1e-3;%读入孔距
            app.D2=app.DmEditField_2.Value;%读入观察屏距离

            app.x2 = linspace(app.xa2, app.xb2, app.N12);  % x坐标
            app.y2 = linspace(app.ya2, app.yb2, app.N22);  % y坐标
            [app.x2, app.y2] = meshgrid(app.x2, app.y2);
            app.lambda_values = [
                app.nmEditField_2.Value*1e-9; % 红光
                app.nmEditField_8.Value*1e-9; % 橙光
                app.nmEditField_3.Value*1e-9; % 黄光
                app.nmEditField_4.Value*1e-9; % 绿光
                app.nmEditField_5.Value*1e-9; % 青光
                app.nmEditField_6.Value*1e-9; % 蓝光
                app.nmEditField_7.Value*1e-9; % 紫光
            ];

            app.intensity_ratios = [
                app.Slider.Value; % 红光比例
                app.Slider_4.Value; % 橙光比例
                app.Slider_6.Value; % 黄光比例
                app.Slider_5.Value; % 绿光比例
                app.Slider_7.Value; % 青光比例
                app.Slider_3.Value; % 蓝光比例
                app.Slider_2.Value; % 紫光比例
                ];
        end
        
        function stripeimage (app,~)
            % 绘制条纹图像
            getvalue(app);
            gray_image = mat2gray(app.I);% 将光强转换为灰度图
            color = wavelength_to_rgb_fixed(app, app.lambda * 1e9); % 选择对应波长的颜色
            % 将灰度图转换为彩色图
            if any(color ~= [0, 0, 0]) % 如果颜色不是黑色（代表波长在可见光范围内）
                color_image = bsxfun(@times, gray_image, reshape(color, 1, 1, 3));
            else % 如果颜色是黑色（代表波长不在可见光范围内），则设置为黑白图
                color_image = repmat(gray_image, [1, 1, 3]);
            end
           imshow(color_image, 'XData', [app.xa app.xb], 'YData', [app.ya app.yb], 'Parent', app.UIAxes);
           xlabel(app.UIAxes,'x (m)','FontSize', 12)
           ylabel(app.UIAxes,'y (m)','FontSize', 12)
           title(app.UIAxes,'杨氏双缝干涉条纹','FontSize', 12)
           axis(app.UIAxes,[app.xa, app.xb, app.ya, app.yb]);% 设置坐标轴范围
           axis(app.UIAxes, 'on');
           x_ticks = app.xa:0.1:app.xb;
           y_ticks = app.ya:0.1:app.yb;
           % 设置X轴和Y轴的刻度
           xticks(app.UIAxes,x_ticks);
           yticks(app.UIAxes,y_ticks);
        end

        function rgb = wavelength_to_rgb_fixed(~,wavelength)
            % 将波长转换为固定颜色的 RGB 颜色值
            % 波长范围：380 nm - 760 nm
            if wavelength >= 380 && wavelength < 440
                r = -(wavelength - 440) / (440 - 380);
                g = 0;
                b = 1;
            elseif wavelength >= 440 && wavelength < 490
                r = 0;
                g = (wavelength - 440) / (490 - 440);
                b = 1;
            elseif wavelength >= 490 && wavelength < 510
                r = 0;
                g = 1;
                b = -(wavelength - 510) / (510 - 490);
            elseif wavelength >= 510 && wavelength < 580
                r = (wavelength - 510) / (580 - 510);
                g = 1;
                b = 0;
            elseif wavelength >= 580 && wavelength < 645
                r = 1;
                g = -(wavelength - 645) / (645 - 580);
                b = 0;
            elseif wavelength >= 645 && wavelength <= 760
                r = 1;
                g = 0;
                b = 0;
            else
                r = 0;
                g = 0;
                b = 0;
            end
            rgb = [r, g, b];
        end

        function light_intensity_image(app,~)
            %绘制光强分布图
            getvalue(app);
            surf(app.UIAxes_2, app.x, app.y, app.I, 'EdgeColor', 'none')
            colormap(app.UIAxes_2, jet)  % 使用 jet 色图
            colorbar(app.UIAxes_2)  % 在 UIAxes_2 上显示色彩条
            xlabel(app.UIAxes_2,'x (m)','FontSize', 12)
            ylabel(app.UIAxes_2,'y (m)','FontSize', 12)
            zlabel(app.UIAxes_2,'光强（cd）','FontSize', 12)
            title(app.UIAxes_2,'光强分布','FontSize', 12)
            % 设置坐标轴范围
            xlim(app.UIAxes_2,[app.xa, app.xb]);
            ylim(app.UIAxes_2,[app.ya, app.yb]);
            zlim(app.UIAxes_2,[0, max(app.I(:))]);  % 设置 z 轴范围为 0 到光强的最大值
            x_ticks = app.xa:0.2:app.xb;
            y_ticks = app.ya:0.2:app.yb;
            % 设置X轴和Y轴的刻度
            xticks(app.UIAxes_2,x_ticks);
            yticks(app.UIAxes_2,y_ticks);
            
        end

        function display_fringe_spacing(app)
            % 计算条纹间距并显示在文本控件中
            app.stripe_spacing=app.lambda*app.D/ app.d;
            app.XmLabel.Text=['条纹间距△X（m）：' num2str(app.stripe_spacing) ' m'];
        end

        function display_moving_distance(app)
            % 计算条纹移动大小与方向
            xk = -((app.n2 - 1)*app.h2-(app.n1 - 1)*app.h1 +app.a2-app.a1)*app.D / app.d;
            if xk > 0
                direction = '上';
            elseif xk < 0
                direction = '下';
            else
                direction = '上/下';
            end
            T=xk/app.stripe_spacing;
            app.m0Label.Text = sprintf('条纹整体向%s移动了%.3f m, 平移约%.2f个周期', direction, abs(xk), abs(T));
        end
 
        function updateView(app, viewType)
            % 调整视图位置
            switch viewType
                case 'x'
                    view(app.UIAxes_2, [0.5, 0])  % X-光强视图
                case 'y'
                    view(app.UIAxes_2, [90, 0])  % Y-光强视图
                case '3D'
                    view(app.UIAxes_2, 3)      % 三维视图
            end

        end

        function toggleEditFields1(app)
            % 启用或禁用遮挡物体折射率和厚度编辑框
            if app.CheckBox.Value
                app.n1EditField.Enable = 'on';
                app.h1mEditField.Enable = 'on';
            else
                app.n1EditField.Enable = 'off';
                app.h1mEditField.Enable = 'off';
                app.n1EditField.Value = 1; % 默认值
                app.h1mEditField.Value = 0; % 默认值
            end

            if app.CheckBox_2.Value
                app.n2EditField.Enable = 'on';
                app.h2mEditField.Enable = 'on';
            else
                app.n2EditField.Enable = 'off';
                app.h2mEditField.Enable = 'off';
                app.n2EditField.Value = 1; % 默认值
                app.h2mEditField.Value = 0; % 默认值
            end
        end

        function clearAxes(app)
            % 清除坐标系
            cla(app.UIAxes);
        end

        function saveImage(app, image)
            % 选择保存文件的位置和格式
            [file, path] = uiputfile({'*.jpg'; '*.png'; '*.bmp'}, 'Save Image As');
            % 如果用户未取消操作
            if ischar(file)
                % 获取完整的文件路径
                fullFileName = fullfile(path, file);
                % 将指定的 UIAxes 中的图像保存到指定路径
                exportgraphics(image, fullFileName);
                % 显示保存成功的消息
                uialert(app.gansheUIFigure, '图像已成功保存！', '成功');
            else
                % 如果用户取消保存
                uialert(app.gansheUIFigure, '操作已取消。', '提示');
            end
        end

        function Composite_light(app)
            % 绘制复合光条纹图
            getvalue2(app);% 获取数据
            r12 = sqrt((app.x2 - app.d2 / 2).^2 + app.y2.^2 + app.D2^2); % 光程1
            r22 = sqrt((app.x2 + app.d2 / 2).^2 + app.y2.^2 + app.D2^2); % 光程2
            I_0 = 1;  % 初始光强
            total_I = zeros(app.N12,app.N22,3); % 初始化总光强矩阵
            sI = zeros(app.N12,app.N22,3); % 每个波长对应光强矩阵

            for i = 1:length(app.lambda_values)
                % 获取每个波长并计算相应的光强
                lambda1 = app.lambda_values(i); % 波长
                k = 2 * pi / lambda1;            % 计算波数
                % 计算相位差
                delta_phi = k * (r22 - r12);
                % 单个波长的光强
                I2 = 4 * I_0 * cos(delta_phi / 2).^2;
                color = wavelength_to_rgb_fixed(app, app.lambda_values(i) * 1e9); % 获取颜色
                sI(:,:,1) = I2 .* color(1)* app.intensity_ratios(i);
                sI(:,:,2) = I2 .* color(2)* app.intensity_ratios(i);
                sI(:,:,3) = I2 .* color(3)* app.intensity_ratios(i);
                total_I = total_I + sI;
                sI=[];

            end
            app.bright = app.Slider_8.Value;% 调整亮度
            app.I_2 = (app.bright*total_I)/max(max(max(total_I)));
                 
            % 显示最终的复色光条纹图像
            imagesc(app.I_2, 'XData', [app.xa2 app.xb2], 'YData', [app.ya2 app.yb2], 'Parent', app.UIAxes2);
            xlabel(app.UIAxes2, 'x (m)', 'FontSize', 12);
            ylabel(app.UIAxes2, 'y (m)', 'FontSize', 12);
            title(app.UIAxes2, '复色光干涉条纹图像', 'FontSize', 12);
            axis(app.UIAxes2, [app.xa2, app.xb2, app.ya2, app.yb2]);  % 设置坐标轴范围
            axis(app.UIAxes2, 'on');
            xticks(app.UIAxes2, app.xa2:0.1:app.xb2);
            yticks(app.UIAxes2, app.ya2:0.1:app.yb2);

        end
        
        function Composite_light_intensity1(app)
            getvalue2(app); % 获取数据
            Composite_light(app);% 条纹图像
            % 将三维图像RGB转换为二维灰度图像
            I_gray = mean(app.I_2./app.bright, 3); % 求RGB三个通道的平均值
            % 沿 y 方向对光强求和，获得 x 方向的光强分布
            I_x = sum(I_gray, 1);
            I_x = I_x/max(I_x);
            % 绘制 x 方向总光强分布
            plot(app.UIAxes2_2, linspace(app.xa2, app.xb2, length(I_x)), I_x);
            xlabel(app.UIAxes2_2, 'x (m)', 'FontSize', 12);
            ylabel(app.UIAxes2_2, '光强（cd）', 'FontSize', 12);
            title(app.UIAxes2_2, 'x方向总光强分布曲线(归一化后)', 'FontSize', 12);
            xticks(app.UIAxes2_2, app.xa2:0.1:app.xb2);
        end
        
        function Composite_light_intensity2(app)
            getvalue2(app); % 获取数据
            Composite_light(app);% 条纹图像
            % 颜色对应
            colors = [
                1, 0, 0;    % 红色
                1, 0.64, 0; % 橙色
                1, 1, 0;    % 黄色
                0, 1, 0;    % 绿色
                0, 1, 1;    % 青色
                0, 0, 1;    % 蓝色
                0.5, 0, 0.5 % 紫色
                ];
            % 清空坐标系
            cla(app.UIAxes2_2);
            hold(app.UIAxes2_2, 'on');
            x_values = linspace(app.xa2, app.xb2, app.N12); % x 轴范围

            % 遍历每个波段的光强，分别绘制
            for i = 1:length(app.lambda_values)
                if app.intensity_ratios(i) > 0
                    % 计算对应波长下的光强分布
                    k = 2 * pi / app.lambda_values(i);
                    r12 = sqrt((app.x2 - app.d2 / 2).^2 + app.y2.^2 + app.D2^2);
                    r22 = sqrt((app.x2 + app.d2 / 2).^2 + app.y2.^2 + app.D2^2);
                    delta_phi = k * (r22 - r12);
                    I3 = 4 * cos(delta_phi / 2).^2;
                    % 获取沿 y 方向的光强分布
                    I_x2 = sum(I3, 1) * app.intensity_ratios(i);
                    % 绘制当前波长的光强分布
                    plot(app.UIAxes2_2, x_values, I_x2, 'Color', colors(i, :), 'LineWidth', 1.5);
                end
            end

            % 设置图像属性
            xlabel(app.UIAxes2_2, 'x(m)', 'FontSize', 12);
            ylabel(app.UIAxes2_2, '光强（cd）', 'FontSize', 12);
            title(app.UIAxes2_2, '七色光对应的光强分布', 'FontSize', 12);
            xticks(app.UIAxes2_2, app.xa2:0.1:app.xb2);
            hold(app.UIAxes2_2, 'off');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 设置app界面居中
            Position_Screen = get(0,'ScreenSize');%获取屏幕尺寸
            Position_App = app.gansheUIFigure.Position;
            Position_App(1) = (Position_Screen(3)-Position_App(3))/2;
            Position_App(2) = (Position_Screen(4)-Position_App(4))/2;
            app.gansheUIFigure.Position = Position_App;
            % 设置遮挡物参数初始化不可编辑
            app.n1EditField.Enable="off";
            app.h1mEditField.Enable="off";
            app.n2EditField.Enable="off";
            app.h2mEditField.Enable="off";
            % 初始绘图
            stripeimage(app);
            light_intensity_image(app);
            display_fringe_spacing(app);
            Composite_light_intensity1(app);


        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            toggleEditFields1(app);
        end

        % Value changed function: xamSpinner
        function xamSpinnerValueChanging(app, event)
            stripeimage(app,event); 
            light_intensity_image(app,event);
        end

        % Value changed function: xbmSpinner
        function xbmSpinnerValueChanging(app, event)
            stripeimage(app,event); 
            light_intensity_image(app,event);
        end

        % Value changed function: yamSpinner
        function yamSpinnerValueChanging(app, event)
            stripeimage(app,event); 
            light_intensity_image(app,event);
        end

        % Value changed function: ybmSpinner
        function ybmSpinnerValueChanging(app, event)
            stripeimage(app,event); 
            light_intensity_image(app,event);
        end

        % Value changed function: xN1Spinner
        function xN1SpinnerValueChanging(app, event)
            stripeimage(app,event); 
            light_intensity_image(app,event);
        end

        % Value changed function: yN2Spinner
        function yN2SpinnerValueChanging(app, event)
            stripeimage(app,event); 
            light_intensity_image(app,event);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            stripeimage(app,event); 
            display_fringe_spacing(app);
            display_moving_distance(app);
        end

        % Button pushed function: Button_7
        function Button_2Pushed(app, event)
            clearAxes(app);
        end

        % Button pushed function: Button_8
        function Button_3Pushed(app, event)
            saveImage(app, app.UIAxes);
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            toggleEditFields1(app);
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            light_intensity_image(app);
            display_fringe_spacing(app);
            display_moving_distance(app);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            cla(app.UIAxes_2);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            saveImage(app,app.UIAxes_2);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            updateView(app, '3D');
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            updateView(app, 'x');
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            updateView(app, 'y');
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            Composite_light(app);
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            Composite_light_intensity1(app);
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            Composite_light_intensity2(app);
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
            saveImage(app,app.UIAxes2);
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            saveImage(app,app.UIAxes2_2);
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
            cla(app.UIAxes2);
        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
            cla(app.UIAxes2_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.gansheUIFigure = uifigure('Visible', 'off');
            app.gansheUIFigure.Name = 'ganshe';
            app.TabGroup = uitabgroup(app.gansheUIFigure);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '实验简介';
            app.Panel_6 = uipanel(app.Tab_3);
            app.Panel_6.Title = '杨氏双缝干涉仿真实验原理介绍';
            app.TextArea = uitextarea(app.Panel_6);
            app.TextArea.Value = {'      杨氏双缝干涉理论是光学领域中的一个重要理论，展示了光的波动性。当一束单色光通过一条狭缝后，再经过一组相隔很小的双缝（S1和S2），光波会在双缝处产生次波源。这些次波源发出的光波在屏幕上相遇并发生干涉，形成明暗相间的干涉条纹。本实验平台可以通过调控单色光的波长λ、双缝间距d、双缝到屏幕的距离D、光源与双缝的距离L、光源上下移动的距离a以及插入折射率为n，厚度为h的玻璃片，来显示不同的干涉条纹图像和光强分布图像。'; '       在复色光干涉实验中，白光或多种不同波长的光线同时通过双缝，干涉图样会变得更加复杂。由于不同波长的光产生的干涉条纹位置不同，最终在屏幕上形成彩色的干涉条纹。这种现象反映了不同波长光的相位差异和光的色散特性。本实验可通过调整7种可见光的波长以及各自的光强比例，可以生成丰富的复色光干涉图样和光强分布曲线。'};
            app.Image = uiimage(app.Panel_6);
            app.Image.ImageSource = fullfile(pathToMLAPP, '1.png');
            app.Image2 = uiimage(app.Panel_6);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '2.png');
            app.Image3 = uiimage(app.Panel_6);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '1.gif');
            app.Label_9 = uilabel(app.Panel_6);
            app.Label_9.Text = '加入玻璃片后的光强公式：';
            app.Image4 = uiimage(app.Panel_6);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '3.png');
            app.Image5 = uiimage(app.Panel_6);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '4.png');
            app.Label_10 = uilabel(app.Panel_6);
            app.Label_10.Text = '光源移动的光强公式：';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '可变参数下的杨氏双缝干涉模拟实验';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Tab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Tooltip = {'输入值必须为正数'};
            app.Panel_2.Title = '实验参数设定';
            app.nmEditFieldLabel = uilabel(app.Panel_2);
            app.nmEditFieldLabel.Text = '波长λ（nm）';
            app.nmEditField = uieditfield(app.Panel_2, 'numeric');
            app.nmEditField.Limits = [0 Inf];
            app.nmEditField.Tooltip = {'输入值必须为正数'};
            app.nmEditField.Value = 300;
            app.dmmEditFieldLabel = uilabel(app.Panel_2);
            app.dmmEditFieldLabel.Text = '孔距d（mm）';
            app.dmmEditField = uieditfield(app.Panel_2, 'numeric');
            app.dmmEditField.Limits = [0 Inf];
            app.dmmEditField.Tooltip = {'输入值必须为正数'};
            app.dmmEditField.Value = 0.05;
            app.CheckBox = uicheckbox(app.Panel_2);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '是否在上光路后添加遮挡物';
            app.n1EditFieldLabel = uilabel(app.Panel_2);
            app.n1EditFieldLabel.Text = '遮挡物体折射率n1';
            app.n1EditField = uieditfield(app.Panel_2, 'numeric');
            app.n1EditField.Limits = [0 Inf];
            app.n1EditField.Tooltip = {'输入值必须为正数'};
            app.n1EditField.Value = 1;
            app.h1mEditFieldLabel = uilabel(app.Panel_2);
            app.h1mEditFieldLabel.Text = '遮挡物体厚度h1（μm）';
            app.h1mEditField = uieditfield(app.Panel_2, 'numeric');
            app.h1mEditField.Limits = [0 Inf];
            app.h1mEditField.Tooltip = {'输入值必须为正数'};
            app.DmLabel = uilabel(app.Panel_2);
            app.DmLabel.Text = '观察屏与双缝间的垂直距离D（m）';
            app.DmEditField = uieditfield(app.Panel_2, 'numeric');
            app.DmEditField.Limits = [0 Inf];
            app.DmEditField.Tooltip = {'输入值必须为正数'};
            app.DmEditField.Value = 20;
            app.n2EditFieldLabel = uilabel(app.Panel_2);
            app.n2EditFieldLabel.Text = '遮挡物体折射率n2';
            app.n2EditField = uieditfield(app.Panel_2, 'numeric');
            app.n2EditField.Limits = [0 Inf];
            app.n2EditField.Tooltip = {'输入值必须为正数'};
            app.n2EditField.Value = 1;
            app.h2mEditFieldLabel = uilabel(app.Panel_2);
            app.h2mEditFieldLabel.Text = '遮挡物体厚度h2（μm）';
            app.h2mEditField = uieditfield(app.Panel_2, 'numeric');
            app.h2mEditField.Limits = [0 Inf];
            app.h2mEditField.Tooltip = {'输入值必须为正数'};
            app.CheckBox_2 = uicheckbox(app.Panel_2);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '是否在下光路后添加遮挡物';
            app.LmmEditFieldLabel = uilabel(app.Panel_2);
            app.LmmEditFieldLabel.Text = '光源与双缝间的垂直距离L（mm）';
            app.LmmEditField = uieditfield(app.Panel_2, 'numeric');
            app.LmmEditField.Limits = [0 Inf];
            app.LmmEditField.Tooltip = {'输入值必须为正数'};
            app.LmmEditField.Value = 1;
            app.amLabel = uilabel(app.Panel_2);
            app.amLabel.Text = '光源与双缝连线中点的垂线之间的距离a（mm）';
            app.ammEditField = uieditfield(app.Panel_2, 'numeric');
            app.ammEditField.Limits = [0 Inf];
            app.ammEditField.Tooltip = {'输入值必须为正数'};
            app.Panel_3 = uipanel(app.Tab);
            app.Panel_3.Tooltip = {'输入值必须为正数'};
            app.Panel_3.Title = '观察范围设置';
            app.xamSpinnerLabel = uilabel(app.Panel_3);
            app.xamSpinnerLabel.Text = '最小横坐标xa（m）';
            app.xamSpinner = uispinner(app.Panel_3);
            app.xamSpinner.Step = 0.5;
            app.xamSpinner.ValueChangedFcn = createCallbackFcn(app, @xamSpinnerValueChanging, true);
            app.xamSpinner.Value = -0.5;
            app.yamSpinnerLabel = uilabel(app.Panel_3);
            app.yamSpinnerLabel.Text = '最小纵坐标ya（m）';
            app.yamSpinner = uispinner(app.Panel_3);
            app.yamSpinner.Step = 0.5;
            app.yamSpinner.ValueChangedFcn = createCallbackFcn(app, @yamSpinnerValueChanging, true);
            app.yamSpinner.Value = -0.5;
            app.xbmSpinnerLabel = uilabel(app.Panel_3);
            app.xbmSpinnerLabel.Text = '最大横坐标xb（m）';
            app.xbmSpinner = uispinner(app.Panel_3);
            app.xbmSpinner.Step = 0.5;
            app.xbmSpinner.ValueChangedFcn = createCallbackFcn(app, @xbmSpinnerValueChanging, true);
            app.xbmSpinner.Value = 0.5;
            app.ybmSpinnerLabel = uilabel(app.Panel_3);
            app.ybmSpinnerLabel.Text = '最大纵坐标yb（m）';
            app.ybmSpinner = uispinner(app.Panel_3);
            app.ybmSpinner.Step = 0.5;
            app.ybmSpinner.ValueChangedFcn = createCallbackFcn(app, @ybmSpinnerValueChanging, true);
            app.ybmSpinner.Value = 0.5;
            app.xN1SpinnerLabel = uilabel(app.Panel_3);
            app.xN1SpinnerLabel.Text = 'x轴方向等分份数N1';
            app.xN1Spinner = uispinner(app.Panel_3);
            app.xN1Spinner.Step = 100;
            app.xN1Spinner.Limits = [0 Inf];
            app.xN1Spinner.ValueChangedFcn = createCallbackFcn(app, @xN1SpinnerValueChanging, true);
            app.xN1Spinner.Value = 1000;
            app.yN2SpinnerLabel = uilabel(app.Panel_3);
            app.yN2SpinnerLabel.Text = 'y轴方向等分份数N2';
            app.yN2Spinner = uispinner(app.Panel_3);
            app.yN2Spinner.Step = 100;
            app.yN2Spinner.Limits = [0 Inf];
            app.yN2Spinner.ValueChangedFcn = createCallbackFcn(app, @yN2SpinnerValueChanging, true);
            app.yN2Spinner.Value = 1000;
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '绘制干涉条纹图像';
            app.Button_4 = uibutton(app.Tab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '绘制光强分布图像';
            app.Button_5 = uibutton(app.Tab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '清除坐标系';
            app.Button_6 = uibutton(app.Tab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '图像另存为';
            app.Button_7 = uibutton(app.Tab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_7.Text = '清除坐标系';
            app.Button_8 = uibutton(app.Tab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_8.Text = '图像另存为';
            app.Button_9 = uibutton(app.Tab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '三维视图';
            app.XButton = uibutton(app.Tab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X—光强视图';
            app.YButton = uibutton(app.Tab, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y—光强视图';
            app.m0Label = uilabel(app.Tab);
            app.m0Label.Tooltip = {'满足D>>d条件'};
            app.m0Label.Text = '条纹整体向上/下移动了0 m, 平移约0个周期';
            app.XmLabel = uilabel(app.Tab);
            app.XmLabel.Tooltip = {'满足D>>d条件'};
            app.XmLabel.Text = '条纹间距△X（m）：';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '复色光双缝干涉模拟实验';
            app.UIAxes2 = uiaxes(app.Tab_2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.Tab_2);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.Button_19 = uibutton(app.Tab_2, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '干涉条纹图像';
            app.Panel_4 = uipanel(app.Tab_2);
            app.Panel_4.Tooltip = {'输入值必须为正数'};
            app.Panel_4.Title = '实验参数设定';
            app.dmmEditField_2Label = uilabel(app.Panel_4);
            app.dmmEditField_2Label.Text = '孔距d（mm）';
            app.dmmEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.dmmEditField_2.Limits = [0 Inf];
            app.dmmEditField_2.Tooltip = {'输入值必须为正数'};
            app.dmmEditField_2.Value = 0.1;
            app.DmEditField_2Label = uilabel(app.Panel_4);
            app.DmEditField_2Label.Text = '观察屏与双缝间垂直距离D（m）';
            app.DmEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.DmEditField_2.Limits = [0 Inf];
            app.DmEditField_2.Tooltip = {'输入值必须为正数'};
            app.DmEditField_2.Value = 20;
            app.nmLabel = uilabel(app.Panel_4);
            app.nmLabel.Text = '红光波长范围：645-760nm';
            app.nmEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.nmEditField_2.Limits = [645 760];
            app.nmEditField_2.Tooltip = {'输入值需为正数'};
            app.nmEditField_2.Value = 645;
            app.Label = uilabel(app.Panel_4);
            app.Label.Text = '红光强度比例';
            app.Slider = uislider(app.Panel_4);
            app.Slider.Limits = [0 1];
            app.Slider.Value = 0.5;
            app.nmLabel_2 = uilabel(app.Panel_4);
            app.nmLabel_2.Text = '橙光波长范围：600-645nm';
            app.nmEditField_8 = uieditfield(app.Panel_4, 'numeric');
            app.nmEditField_8.Limits = [600 645];
            app.nmEditField_8.Tooltip = {'输入值需为正数'};
            app.nmEditField_8.Value = 600;
            app.Label_2 = uilabel(app.Panel_4);
            app.Label_2.Text = '橙光强度比例';
            app.Slider_4 = uislider(app.Panel_4);
            app.Slider_4.Limits = [0 1];
            app.Slider_4.Value = 0.5;
            app.nmLabel_3 = uilabel(app.Panel_4);
            app.nmLabel_3.Text = '黄光波长范围：580-600nm';
            app.nmEditField_3 = uieditfield(app.Panel_4, 'numeric');
            app.nmEditField_3.Limits = [580 600];
            app.nmEditField_3.Tooltip = {'输入值需为正数'};
            app.nmEditField_3.Value = 580;
            app.Label_3 = uilabel(app.Panel_4);
            app.Label_3.Text = '黄光强度比例';
            app.Slider_6 = uislider(app.Panel_4);
            app.Slider_6.Limits = [0 1];
            app.Slider_6.Value = 0.5;
            app.nmLabel_4 = uilabel(app.Panel_4);
            app.nmLabel_4.Text = '绿光波长范围：510-580nm';
            app.nmEditField_4 = uieditfield(app.Panel_4, 'numeric');
            app.nmEditField_4.Limits = [510 580];
            app.nmEditField_4.Tooltip = {'输入值需为正数'};
            app.nmEditField_4.Value = 510;
            app.Label_5 = uilabel(app.Panel_4);
            app.Label_5.Text = '绿光强度比例';
            app.Slider_5 = uislider(app.Panel_4);
            app.Slider_5.Limits = [0 1];
            app.Slider_5.Value = 0.5;
            app.nmLabel_5 = uilabel(app.Panel_4);
            app.nmLabel_5.Text = '青(靛)光波长范围：490-510nm';
            app.nmEditField_5 = uieditfield(app.Panel_4, 'numeric');
            app.nmEditField_5.Limits = [490 510];
            app.nmEditField_5.Tooltip = {'输入值需为正数'};
            app.nmEditField_5.Value = 490;
            app.Label_6 = uilabel(app.Panel_4);
            app.Label_6.Text = '青(靛)光强度比例';
            app.Slider_7 = uislider(app.Panel_4);
            app.Slider_7.Limits = [0 1];
            app.Slider_7.Value = 0.5;
            app.nmLabel_6 = uilabel(app.Panel_4);
            app.nmLabel_6.Text = '蓝光波长范围：440-490nm';
            app.nmEditField_6 = uieditfield(app.Panel_4, 'numeric');
            app.nmEditField_6.Limits = [440 490];
            app.nmEditField_6.Tooltip = {'输入值需为正数'};
            app.nmEditField_6.Value = 440;
            app.Label_7 = uilabel(app.Panel_4);
            app.Label_7.Text = '蓝光强度比例';
            app.Slider_3 = uislider(app.Panel_4);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.Value = 0.5;
            app.nmLabel_7 = uilabel(app.Panel_4);
            app.nmLabel_7.Text = '紫光波长范围：380-440nm';
            app.nmEditField_7 = uieditfield(app.Panel_4, 'numeric');
            app.nmEditField_7.Limits = [380 440];
            app.nmEditField_7.Tooltip = {'输入值需为正数'};
            app.nmEditField_7.Value = 380;
            app.Label_8 = uilabel(app.Panel_4);
            app.Label_8.Text = '紫光强度比例';
            app.Slider_2 = uislider(app.Panel_4);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.Value = 0.5;
            app.Label_4 = uilabel(app.Panel_4);
            app.Label_4.Text = '图像整体亮度(倍)';
            app.Slider_8 = uislider(app.Panel_4);
            app.Slider_8.Limits = [1 2];
            app.Slider_8.Value = 1.2;
            app.Panel_5 = uipanel(app.Tab_2);
            app.Panel_5.Tooltip = {'输入值必须为正数'};
            app.Panel_5.Title = '观察范围设置';
            app.xamSpinner_2Label = uilabel(app.Panel_5);
            app.xamSpinner_2Label.Text = '最小横坐标xa（m）';
            app.xamSpinner_2 = uispinner(app.Panel_5);
            app.xamSpinner_2.Step = 0.5;
            app.xamSpinner_2.Value = -0.5;
            app.yamSpinner_2Label = uilabel(app.Panel_5);
            app.yamSpinner_2Label.Text = '最小纵坐标ya（m）';
            app.yamSpinner_2 = uispinner(app.Panel_5);
            app.yamSpinner_2.Step = 0.5;
            app.yamSpinner_2.Value = -0.5;
            app.xbmSpinner_2Label = uilabel(app.Panel_5);
            app.xbmSpinner_2Label.Text = '最大横坐标xb（m）';
            app.xbmSpinner_2 = uispinner(app.Panel_5);
            app.xbmSpinner_2.Step = 0.5;
            app.xbmSpinner_2.Value = 0.5;
            app.ybmSpinner_2Label = uilabel(app.Panel_5);
            app.ybmSpinner_2Label.Text = '最大纵坐标yb（m）';
            app.ybmSpinner_2 = uispinner(app.Panel_5);
            app.ybmSpinner_2.Step = 0.5;
            app.ybmSpinner_2.Value = 0.5;
            app.xN1Spinner_2Label = uilabel(app.Panel_5);
            app.xN1Spinner_2Label.Text = 'x轴方向等分份数N1';
            app.xN1Spinner_2 = uispinner(app.Panel_5);
            app.xN1Spinner_2.Step = 100;
            app.xN1Spinner_2.Limits = [0 Inf];
            app.xN1Spinner_2.Value = 1000;
            app.yN2Spinner_2Label = uilabel(app.Panel_5);
            app.yN2Spinner_2Label.Text = 'y轴方向等分份数N2';
            app.yN2Spinner_2 = uispinner(app.Panel_5);
            app.yN2Spinner_2.Step = 100;
            app.yN2Spinner_2.Limits = [0 Inf];
            app.yN2Spinner_2.Value = 1000;
            app.Button_20 = uibutton(app.Tab_2, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '总光强分布曲线';
            app.Button_21 = uibutton(app.Tab_2, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = {'各波段'; '光强分布曲线'};
            app.Button_22 = uibutton(app.Tab_2, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '图像另存为';
            app.Button_23 = uibutton(app.Tab_2, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '图像另存为';
            app.Button_24 = uibutton(app.Tab_2, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = '清除坐标系';
            app.Button_25 = uibutton(app.Tab_2, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Text = '清除坐标系';
            % Show the figure after all components are created
            app.gansheUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ganshe1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.gansheUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.gansheUIFigure)
        end
    end
end