classdef ganshe2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        Panel_2             matlab.ui.container.Panel
        Image4              matlab.ui.control.Image
        nLabel              matlab.ui.control.Label
        Label_11            matlab.ui.control.Label
        Label_10            matlab.ui.control.Label
        Label_9             matlab.ui.control.Label
        Image3              matlab.ui.control.Image
        Image2              matlab.ui.control.Image
        Image               matlab.ui.control.Image
        TextArea            matlab.ui.control.TextArea
        Tab_2               matlab.ui.container.Tab
        Button_6            matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        Label_8             matlab.ui.control.Label
        NANLabel_4          matlab.ui.control.Label
        NANLabel_3          matlab.ui.control.Label
        NANLabel_2          matlab.ui.control.Label
        NANLabel            matlab.ui.control.Label
        Label_7             matlab.ui.control.Label
        Label_6             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        ymEditField_2       matlab.ui.control.NumericEditField
        ymEditField_2Label  matlab.ui.control.Label
        xmEditField_7       matlab.ui.control.NumericEditField
        xmLabel_8           matlab.ui.control.Label
        ymEditField_3       matlab.ui.control.NumericEditField
        ymLabel             matlab.ui.control.Label
        xmEditField_6       matlab.ui.control.NumericEditField
        xmLabel_7           matlab.ui.control.Label
        ymEditField_4       matlab.ui.control.NumericEditField
        ymEditField_4Label  matlab.ui.control.Label
        xmEditField_5       matlab.ui.control.NumericEditField
        xmLabel_6           matlab.ui.control.Label
        ymEditField_5       matlab.ui.control.NumericEditField
        xmLabel_5           matlab.ui.control.Label
        xmEditField_4       matlab.ui.control.NumericEditField
        xmLabel_4           matlab.ui.control.Label
        ymEditField_6       matlab.ui.control.NumericEditField
        xmLabel_3           matlab.ui.control.Label
        xmEditField_3       matlab.ui.control.NumericEditField
        xmLabel_2           matlab.ui.control.Label
        ymEditField_7       matlab.ui.control.NumericEditField
        ymEditField_7Label  matlab.ui.control.Label
        xmEditField_2       matlab.ui.control.NumericEditField
        xmLabel             matlab.ui.control.Label
        EditField           matlab.ui.control.NumericEditField
        Label_4             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        zmEditField         matlab.ui.control.NumericEditField
        zmLabel             matlab.ui.control.Label
        ymEditField         matlab.ui.control.NumericEditField
        ymEditFieldLabel    matlab.ui.control.Label
        xmEditField         matlab.ui.control.NumericEditField
        xmEditFieldLabel    matlab.ui.control.Label
        mmEditField         matlab.ui.control.NumericEditField
        mmLabel             matlab.ui.control.Label
        A0Slider            matlab.ui.control.Slider
        Label_2             matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        Label               matlab.ui.control.Label
        yN2Spinner          matlab.ui.control.Spinner
        yN2SpinnerLabel     matlab.ui.control.Label
        xN1Spinner          matlab.ui.control.Spinner
        xN1SpinnerLabel     matlab.ui.control.Label
        ybmSpinner          matlab.ui.control.Spinner
        ybmSpinnerLabel     matlab.ui.control.Label
        xbmSpinner          matlab.ui.control.Spinner
        xbmSpinnerLabel     matlab.ui.control.Label
        yamSpinner          matlab.ui.control.Spinner
        yamSpinnerLabel     matlab.ui.control.Label
        xamSpinner          matlab.ui.control.Spinner
        xamSpinnerLabel     matlab.ui.control.Label
        DmEditField         matlab.ui.control.NumericEditField
        DmLabel             matlab.ui.control.Label
        nmEditField         matlab.ui.control.NumericEditField
        nmEditFieldLabel    matlab.ui.control.Label
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        lambda;D;m;x0;y0;z0;num;s1x;s1y;s2x;s2y;s3x;s3y;s4x;s4y;
        s5x;s5y;s6x;s6y;A0;xa;xb;ya;yb;N1;N2;I1;I2;I3;I4;I5;% Description
    end
    
    methods (Access = private)
        
        function getvalue(app)
            app.lambda = app.nmEditField.Value*1e-9;%波长
            app.D = app.DmEditField.Value;% 小孔平面到光屏的距离
            app.m = app.mmEditField.Value*1e-6;% 小孔两两间的距离
            app.x0 = app.xmEditField.Value;% 光源x坐标
            app.y0 = app.ymEditField.Value;
            app.z0 = app.zmEditField.Value;
            app.num = app.EditField.Value;%小孔数量
            app.s1x = app.xmEditField_2.Value*1e-6;%小孔1x坐标
            app.s1y = app.ymEditField_7.Value*1e-6;
            app.s2x = app.xmEditField_3.Value*1e-6;
            app.s2y = app.ymEditField_6.Value*1e-6;
            app.s3x = app.xmEditField_4.Value*1e-6;
            app.s3y = app.ymEditField_5.Value*1e-6;
            app.s4x = app.xmEditField_5.Value*1e-6;
            app.s4y = app.ymEditField_4.Value*1e-6;
            app.s5x = app.xmEditField_6.Value*1e-6;
            app.s5y = app.ymEditField_3.Value*1e-6;
            app.s6x = app.xmEditField_7.Value*1e-6;
            app.s6y = app.ymEditField_2.Value*1e-6;

            app.A0 = app.A0Slider.Value;%光源振幅
            app.xa=app.xamSpinner.Value; %最小的横坐标值
            app.xb=app.xbmSpinner.Value;%最大的横坐标值
            app.ya=app.yamSpinner.Value;%最小的纵坐标值
            app.yb=app.ybmSpinner.Value;%最大的纵坐标值
            app.N1=app.xN1Spinner.Value;%x方向等分份数
            app.N2=app.yN2Spinner.Value;%y方向等分份数
        end

        function rgb = wavelength_to_rgb_fixed(~,wavelength)
            % 将波长转换为固定颜色的 RGB 颜色值
            % 波长范围：380 nm - 760 nm
            if wavelength >= 380 && wavelength < 440
                r = -(wavelength - 440) / (440 - 380);
                g = 0;
                b = 1;
            elseif wavelength >= 440 && wavelength < 490
                r = 0;
                g = (wavelength - 440) / (490 - 440);
                b = 1;
            elseif wavelength >= 490 && wavelength < 510
                r = 0;
                g = 1;
                b = -(wavelength - 510) / (510 - 490);
            elseif wavelength >= 510 && wavelength < 580
                r = (wavelength - 510) / (580 - 510);
                g = 1;
                b = 0;
            elseif wavelength >= 580 && wavelength < 645
                r = 1;
                g = -(wavelength - 645) / (645 - 580);
                b = 0;
            elseif wavelength >= 645 && wavelength <= 760
                r = 1;
                g = 0;
                b = 0;
            else
                r = 0;
                g = 0;
                b = 0;
            end
            rgb = [r, g, b];
        end
        
        function light1(app)
            % 绘制条纹图像
            getvalue(app);
            x1=linspace(app.xa,app.xb,app.N1);   %x坐标
            y1=linspace(app.ya,app.yb,app.N2);   %y坐标
            [x1, y1]=meshgrid(x1,y1);
            r1 = sqrt((x1-app.s1x)^2 + (y1-app.s1y)^2 + app.D^2);% 孔1光路
            r2 = sqrt((x1-app.s2x)^2 + (y1-app.s2y)^2 + app.D^2);% 孔2光路
            r01 = sqrt((app.x0-app.s1x)^2 + (app.y0-app.s1y)^2 + app.z0^2);% 光源到孔1的距离
            r02 = sqrt((app.x0-app.s2x)^2 + (app.y0-app.s2y)^2 + app.z0^2);
            phi1 = 2*pi*r1/app.lambda;
            phi2 = 2*pi*r2/app.lambda;
            E = (app.A0/r01)*exp(1i*phi1) + (app.A0/r02)*exp(1i*phi2);% 电场
            app.I1 = E.*conj(E); % 光强
            
            gray_image = mat2gray(app.I1);% 将光强转换为灰度图
            color = wavelength_to_rgb_fixed(app, app.lambda * 1e9); % 选择对应波长的颜色
            % 将灰度图转换为彩色图
            if any(color ~= [0, 0, 0]) % 如果颜色不是黑色（代表波长在可见光范围内）
                color_image = bsxfun(@times, gray_image, reshape(color, 1, 1, 3));
            else % 如果颜色是黑色（代表波长不在可见光范围内），则设置为黑白图
                color_image = repmat(gray_image, [1, 1, 3]);
            end
            imshow(color_image, 'XData', [app.xa app.xb], 'YData', [app.ya app.yb], 'Parent', app.UIAxes);
            xlabel(app.UIAxes,'x (m)','FontSize', 12)
            ylabel(app.UIAxes,'y (m)','FontSize', 12)
            title(app.UIAxes,'双孔干涉条纹（水平排列）','FontSize', 12)
            axis(app.UIAxes,[app.xa, app.xb, app.ya, app.yb]);% 设置坐标轴范围
            axis(app.UIAxes, 'on');
            x_ticks = app.xa:0.1:app.xb;
            y_ticks = app.ya:0.1:app.yb;
            % 设置X轴和Y轴的刻度
            xticks(app.UIAxes,x_ticks);
            yticks(app.UIAxes,y_ticks);

        end
        
        function light2(app)
            % 绘制条纹图像
            getvalue(app);
            x2=linspace(app.xa,app.xb,app.N1);   %x坐标
            y2=linspace(app.ya,app.yb,app.N2);   %y坐标
            [x2, y2]=meshgrid(x2,y2);
            r1 = sqrt((x2-app.s1x)^2 + (y2-app.s1y)^2 + app.D^2);% 孔1光路
            r2 = sqrt((x2-app.s2x)^2 + (y2-app.s2y)^2 + app.D^2);% 孔2光路
            r3 = sqrt((x2-app.s3x)^2 + (y2-app.s3y)^2 + app.D^2);% 孔3光路
            r01 = sqrt((app.x0-app.s1x)^2 + (app.y0-app.s1y)^2 + app.z0^2);% 光源到孔1的距离
            r02 = sqrt((app.x0-app.s2x)^2 + (app.y0-app.s2y)^2 + app.z0^2);
            r03 = sqrt((app.x0-app.s3x)^2 + (app.y0-app.s3y)^2 + app.z0^2);
            phi1 = 2*pi*r1/app.lambda;
            phi2 = 2*pi*r2/app.lambda;
            phi3 = 2*pi*r3/app.lambda;
            E = (app.A0/r01)*exp(1i*phi1) + (app.A0/r02)*exp(1i*phi2) + (app.A0/r03)*exp(1i*phi3);% 电场
            app.I2 = E.*conj(E); % 光强
            
            gray_image = mat2gray(app.I2);% 将光强转换为灰度图
            color = wavelength_to_rgb_fixed(app, app.lambda * 1e9); % 选择对应波长的颜色
            % 将灰度图转换为彩色图
            if any(color ~= [0, 0, 0]) % 如果颜色不是黑色（代表波长在可见光范围内）
                color_image = bsxfun(@times, gray_image, reshape(color, 1, 1, 3));
            else % 如果颜色是黑色（代表波长不在可见光范围内），则设置为黑白图
                color_image = repmat(gray_image, [1, 1, 3]);
            end
            imshow(color_image, 'XData', [app.xa app.xb], 'YData', [app.ya app.yb], 'Parent', app.UIAxes);
            xlabel(app.UIAxes,'x (m)','FontSize', 12)
            ylabel(app.UIAxes,'y (m)','FontSize', 12)
            title(app.UIAxes,'三孔干涉条纹（等边三角形排列）','FontSize', 12)
            axis(app.UIAxes,[app.xa, app.xb, app.ya, app.yb]);% 设置坐标轴范围
            axis(app.UIAxes, 'on');
            x_ticks = app.xa:0.1:app.xb;
            y_ticks = app.ya:0.1:app.yb;
            % 设置X轴和Y轴的刻度
            xticks(app.UIAxes,x_ticks);
            yticks(app.UIAxes,y_ticks);
        end
     
        function light3(app)
            % 绘制条纹图像
            getvalue(app);
            x3=linspace(app.xa,app.xb,app.N1);   %x坐标
            y3=linspace(app.ya,app.yb,app.N2);   %y坐标
            [x3, y3]=meshgrid(x3,y3);
            r1 = sqrt((x3-app.s1x)^2 + (y3-app.s1y)^2 + app.D^2);% 孔1光路
            r2 = sqrt((x3-app.s2x)^2 + (y3-app.s2y)^2 + app.D^2);% 孔2光路
            r3 = sqrt((x3-app.s3x)^2 + (y3-app.s3y)^2 + app.D^2);% 孔3光路
            r4 = sqrt((x3-app.s4x)^2 + (y3-app.s4y)^2 + app.D^2);% 孔4光路
            r01 = sqrt((app.x0-app.s1x)^2 + (app.y0-app.s1y)^2 + app.z0^2);% 光源到孔1的距离
            r02 = sqrt((app.x0-app.s2x)^2 + (app.y0-app.s2y)^2 + app.z0^2);
            r03 = sqrt((app.x0-app.s3x)^2 + (app.y0-app.s3y)^2 + app.z0^2);
            r04 = sqrt((app.x0-app.s4x)^2 + (app.y0-app.s4y)^2 + app.z0^2);
            phi1 = 2*pi*r1/app.lambda;
            phi2 = 2*pi*r2/app.lambda;
            phi3 = 2*pi*r3/app.lambda;
            phi4 = 2*pi*r4/app.lambda;
            E = (app.A0/r01)*exp(1i*phi1) + (app.A0/r02)*exp(1i*phi2) + (app.A0/r03)*exp(1i*phi3) + (app.A0/r04)*exp(1i*phi4);% 电场
            app.I3 = E.*conj(E); % 光强
            
            gray_image = mat2gray(app.I3);% 将光强转换为灰度图
            color = wavelength_to_rgb_fixed(app, app.lambda * 1e9); % 选择对应波长的颜色
            % 将灰度图转换为彩色图
            if any(color ~= [0, 0, 0]) % 如果颜色不是黑色（代表波长在可见光范围内）
                color_image = bsxfun(@times, gray_image, reshape(color, 1, 1, 3));
            else % 如果颜色是黑色（代表波长不在可见光范围内），则设置为黑白图
                color_image = repmat(gray_image, [1, 1, 3]);
            end
            imshow(color_image, 'XData', [app.xa app.xb], 'YData', [app.ya app.yb], 'Parent', app.UIAxes);
            xlabel(app.UIAxes,'x (m)','FontSize', 12)
            ylabel(app.UIAxes,'y (m)','FontSize', 12)
            title(app.UIAxes,'四孔干涉条纹（水平排列）','FontSize', 12)
            axis(app.UIAxes,[app.xa, app.xb, app.ya, app.yb]);% 设置坐标轴范围
            axis(app.UIAxes, 'on');
            x_ticks = app.xa:0.1:app.xb;
            y_ticks = app.ya:0.1:app.yb;
            % 设置X轴和Y轴的刻度
            xticks(app.UIAxes,x_ticks);
            yticks(app.UIAxes,y_ticks);
            
        end
        
        function light4(app)
            % 绘制条纹图像
            getvalue(app);
            x4=linspace(app.xa,app.xb,app.N1);   %x坐标
            y4=linspace(app.ya,app.yb,app.N2);   %y坐标
            [x4, y4]=meshgrid(x4,y4);
            r1 = sqrt((x4-app.s1x)^2 + (y4-app.s1y)^2 + app.D^2);% 孔1光路
            r2 = sqrt((x4-app.s2x)^2 + (y4-app.s2y)^2 + app.D^2);% 孔2光路
            r3 = sqrt((x4-app.s3x)^2 + (y4-app.s3y)^2 + app.D^2);% 孔3光路
            r4 = sqrt((x4-app.s4x)^2 + (y4-app.s4y)^2 + app.D^2);% 孔4光路
            r01 = sqrt((app.x0-app.s1x)^2 + (app.y0-app.s1y)^2 + app.z0^2);% 光源到孔1的距离
            r02 = sqrt((app.x0-app.s2x)^2 + (app.y0-app.s2y)^2 + app.z0^2);
            r03 = sqrt((app.x0-app.s3x)^2 + (app.y0-app.s3y)^2 + app.z0^2);
            r04 = sqrt((app.x0-app.s4x)^2 + (app.y0-app.s4y)^2 + app.z0^2);
            phi1 = 2*pi*r1/app.lambda;
            phi2 = 2*pi*r2/app.lambda;
            phi3 = 2*pi*r3/app.lambda;
            phi4 = 2*pi*r4/app.lambda;
            E = (app.A0/r01)*exp(1i*phi1) + (app.A0/r02)*exp(1i*phi2) + (app.A0/r03)*exp(1i*phi3) + (app.A0/r04)*exp(1i*phi4);% 电场
            app.I4 = E.*conj(E); % 光强
            
            gray_image = mat2gray(app.I4);% 将光强转换为灰度图
            color = wavelength_to_rgb_fixed(app, app.lambda * 1e9); % 选择对应波长的颜色
            % 将灰度图转换为彩色图
            if any(color ~= [0, 0, 0]) % 如果颜色不是黑色（代表波长在可见光范围内）
                color_image = bsxfun(@times, gray_image, reshape(color, 1, 1, 3));
            else % 如果颜色是黑色（代表波长不在可见光范围内），则设置为黑白图
                color_image = repmat(gray_image, [1, 1, 3]);
            end
            imshow(color_image, 'XData', [app.xa app.xb], 'YData', [app.ya app.yb], 'Parent', app.UIAxes);
            xlabel(app.UIAxes,'x (m)','FontSize', 12)
            ylabel(app.UIAxes,'y (m)','FontSize', 12)
            title(app.UIAxes,'四孔干涉条纹（正方形排列）','FontSize', 12)
            axis(app.UIAxes,[app.xa, app.xb, app.ya, app.yb]);% 设置坐标轴范围
            axis(app.UIAxes, 'on');
            x_ticks = app.xa:0.1:app.xb;
            y_ticks = app.ya:0.1:app.yb;
            % 设置X轴和Y轴的刻度
            xticks(app.UIAxes,x_ticks);
            yticks(app.UIAxes,y_ticks);
        end
 
        function light5(app)
            % 绘制条纹图像
            getvalue(app);
            x5=linspace(app.xa,app.xb,app.N1);   %x坐标
            y5=linspace(app.ya,app.yb,app.N2);   %y坐标
            [x5, y5]=meshgrid(x5,y5);
            r1 = sqrt((x5-app.s1x)^2 + (y5-app.s1y)^2 + app.D^2);% 孔1光路
            r2 = sqrt((x5-app.s2x)^2 + (y5-app.s2y)^2 + app.D^2);% 孔2光路
            r3 = sqrt((x5-app.s3x)^2 + (y5-app.s3y)^2 + app.D^2);% 孔3光路
            r4 = sqrt((x5-app.s4x)^2 + (y5-app.s4y)^2 + app.D^2);% 孔4光路
            r5 = sqrt((x5-app.s5x)^2 + (y5-app.s5y)^2 + app.D^2);% 孔5光路
            r6 = sqrt((x5-app.s6x)^2 + (y5-app.s6y)^2 + app.D^2);% 孔6光路
            r01 = sqrt((app.x0-app.s1x)^2 + (app.y0-app.s1y)^2 + app.z0^2);% 光源到孔1的距离
            r02 = sqrt((app.x0-app.s2x)^2 + (app.y0-app.s2y)^2 + app.z0^2);
            r03 = sqrt((app.x0-app.s3x)^2 + (app.y0-app.s3y)^2 + app.z0^2);
            r04 = sqrt((app.x0-app.s4x)^2 + (app.y0-app.s4y)^2 + app.z0^2);
            r05 = sqrt((app.x0-app.s5x)^2 + (app.y0-app.s5y)^2 + app.z0^2);
            r06 = sqrt((app.x0-app.s6x)^2 + (app.y0-app.s6y)^2 + app.z0^2);
            phi1 = (2*pi*(r1+r01))/app.lambda;phi4 = (2*pi*(r4+r04))/app.lambda;
            phi2 = (2*pi*(r2+r02))/app.lambda;phi5 = (2*pi*(r5+r05))/app.lambda;
            phi3 = (2*pi*(r3+r03))/app.lambda;phi6 = (2*pi*(r6+r06))/app.lambda;

            if app.num == 2
                E = (app.A0/r01)*exp(1i*phi1) + (app.A0/r02)*exp(1i*phi2);% 电场
            elseif app.num == 3
                E = (app.A0/r01)*exp(1i*phi1) + (app.A0/r02)*exp(1i*phi2) + (app.A0/r03)*exp(1i*phi3);% 电场
            elseif app.num == 4
                E = (app.A0/r01)*exp(1i*phi1) + (app.A0/r02)*exp(1i*phi2) + (app.A0/r03)*exp(1i*phi3) + (app.A0/r04)*exp(1i*phi4);% 电场
            elseif app.num == 5
                E = (app.A0/r01)*exp(1i*phi1) + (app.A0/r02)*exp(1i*phi2) + (app.A0/r03)*exp(1i*phi3) + (app.A0/r04)*exp(1i*phi4) + (app.A0/r05)*exp(1i*phi5);% 电场
            elseif app.num == 6
                E = (app.A0/r01)*exp(1i*phi1) + (app.A0/r02)*exp(1i*phi2) + (app.A0/r03)*exp(1i*phi3) + (app.A0/r04)*exp(1i*phi4) + (app.A0/r05)*exp(1i*phi5) + (app.A0/r06)*exp(1i*phi6);% 电场
            end
            
            app.I5 = E.*conj(E); % 光强
            gray_image = mat2gray(app.I5);% 将光强转换为灰度图
            color = wavelength_to_rgb_fixed(app, app.lambda * 1e9); % 选择对应波长的颜色
            % 将灰度图转换为彩色图
            if any(color ~= [0, 0, 0]) % 如果颜色不是黑色（代表波长在可见光范围内）
                color_image = bsxfun(@times, gray_image, reshape(color, 1, 1, 3));
            else % 如果颜色是黑色（代表波长不在可见光范围内），则设置为黑白图
                color_image = repmat(gray_image, [1, 1, 3]);
            end
            imshow(color_image, 'XData', [app.xa app.xb], 'YData', [app.ya app.yb], 'Parent', app.UIAxes);
            xlabel(app.UIAxes,'x (m)','FontSize', 12)
            ylabel(app.UIAxes,'y (m)','FontSize', 12)
            title(app.UIAxes,'自定义多孔干涉条纹','FontSize', 12)
            axis(app.UIAxes,[app.xa, app.xb, app.ya, app.yb]);% 设置坐标轴范围
            axis(app.UIAxes, 'on');
            x_ticks = app.xa:0.1:app.xb;
            y_ticks = app.ya:0.1:app.yb;
            % 设置X轴和Y轴的刻度
            xticks(app.UIAxes,x_ticks);
            yticks(app.UIAxes,y_ticks);
        end
        
        function light_intensity1(app)
            getvalue(app);
            light1(app);
            x=linspace(app.xa,app.xb,app.N1);   %x坐标
            y=linspace(app.ya,app.yb,app.N2);   %y坐标
            [x, y]=meshgrid(x,y);
            surf(app.UIAxes_2, x, y, app.I1, 'EdgeColor', 'none')
            colormap(app.UIAxes_2, jet)  % 使用 jet 色图
            colorbar(app.UIAxes_2)  % 在 UIAxes_2 上显示色彩条
            xlabel(app.UIAxes_2,'x (m)','FontSize', 12)
            ylabel(app.UIAxes_2,'y (m)','FontSize', 12)
            zlabel(app.UIAxes_2,'光强（cd）','FontSize', 12)
            title(app.UIAxes_2,'光强分布','FontSize', 12)
            % 设置坐标轴范围
            xlim(app.UIAxes_2,[app.xa, app.xb]);
            ylim(app.UIAxes_2,[app.ya, app.yb]);
            zlim(app.UIAxes_2,[0, max(app.I1(:))]);  % 设置 z 轴范围为 0 到光强的最大值
            x_ticks = app.xa:0.2:app.xb;
            y_ticks = app.ya:0.2:app.yb;
            % 设置X轴和Y轴的刻度
            xticks(app.UIAxes_2,x_ticks);
            yticks(app.UIAxes_2,y_ticks);

        end
        
        function light_intensity2(app)
            getvalue(app);
            light2(app);
            x=linspace(app.xa,app.xb,app.N1);   %x坐标
            y=linspace(app.ya,app.yb,app.N2);   %y坐标
            [x, y]=meshgrid(x,y);
            surf(app.UIAxes_2, x, y, app.I2, 'EdgeColor', 'none')
            colormap(app.UIAxes_2, jet)  % 使用 jet 色图
            colorbar(app.UIAxes_2)  % 在 UIAxes_2 上显示色彩条
            xlabel(app.UIAxes_2,'x (m)','FontSize', 12)
            ylabel(app.UIAxes_2,'y (m)','FontSize', 12)
            zlabel(app.UIAxes_2,'光强（cd）','FontSize', 12)
            title(app.UIAxes_2,'光强分布','FontSize', 12)
            % 设置坐标轴范围
            xlim(app.UIAxes_2,[app.xa, app.xb]);
            ylim(app.UIAxes_2,[app.ya, app.yb]);
            zlim(app.UIAxes_2,[0, max(app.I2(:))]);  % 设置 z 轴范围为 0 到光强的最大值
            x_ticks = app.xa:0.2:app.xb;
            y_ticks = app.ya:0.2:app.yb;
            % 设置X轴和Y轴的刻度
            xticks(app.UIAxes_2,x_ticks);
            yticks(app.UIAxes_2,y_ticks);
        end

        function light_intensity3(app)
            getvalue(app);
            light3(app);
            x=linspace(app.xa,app.xb,app.N1);   %x坐标
            y=linspace(app.ya,app.yb,app.N2);   %y坐标
            [x, y]=meshgrid(x,y);
            surf(app.UIAxes_2, x, y, app.I3, 'EdgeColor', 'none')
            colormap(app.UIAxes_2, jet)  % 使用 jet 色图
            colorbar(app.UIAxes_2)  % 在 UIAxes_2 上显示色彩条
            xlabel(app.UIAxes_2,'x (m)','FontSize', 12)
            ylabel(app.UIAxes_2,'y (m)','FontSize', 12)
            zlabel(app.UIAxes_2,'光强（cd）','FontSize', 12)
            title(app.UIAxes_2,'光强分布','FontSize', 12)
            % 设置坐标轴范围
            xlim(app.UIAxes_2,[app.xa, app.xb]);
            ylim(app.UIAxes_2,[app.ya, app.yb]);
            zlim(app.UIAxes_2,[0, max(app.I3(:))]);  % 设置 z 轴范围为 0 到光强的最大值
            x_ticks = app.xa:0.2:app.xb;
            y_ticks = app.ya:0.2:app.yb;
            % 设置X轴和Y轴的刻度
            xticks(app.UIAxes_2,x_ticks);
            yticks(app.UIAxes_2,y_ticks);
        end

        
        function light_intensity4(app)
            getvalue(app);
            light4(app);
            x=linspace(app.xa,app.xb,app.N1);   %x坐标
            y=linspace(app.ya,app.yb,app.N2);   %y坐标
            [x, y]=meshgrid(x,y);
            surf(app.UIAxes_2, x, y, app.I4, 'EdgeColor', 'none')
            colormap(app.UIAxes_2, jet)  % 使用 jet 色图
            colorbar(app.UIAxes_2)  % 在 UIAxes_2 上显示色彩条
            xlabel(app.UIAxes_2,'x (m)','FontSize', 12)
            ylabel(app.UIAxes_2,'y (m)','FontSize', 12)
            zlabel(app.UIAxes_2,'光强（cd）','FontSize', 12)
            title(app.UIAxes_2,'光强分布','FontSize', 12)
            % 设置坐标轴范围
            xlim(app.UIAxes_2,[app.xa, app.xb]);
            ylim(app.UIAxes_2,[app.ya, app.yb]);
            zlim(app.UIAxes_2,[0, max(app.I4(:))]);  % 设置 z 轴范围为 0 到光强的最大值
            x_ticks = app.xa:0.2:app.xb;
            y_ticks = app.ya:0.2:app.yb;
            % 设置X轴和Y轴的刻度
            xticks(app.UIAxes_2,x_ticks);
            yticks(app.UIAxes_2,y_ticks);
        end
     
        function light_intensity5(app)
            getvalue(app);
            light5(app);
            x=linspace(app.xa,app.xb,app.N1);   %x坐标
            y=linspace(app.ya,app.yb,app.N2);   %y坐标
            [x, y]=meshgrid(x,y);
            surf(app.UIAxes_2, x, y, app.I5, 'EdgeColor', 'none')
            colormap(app.UIAxes_2, jet)  % 使用 jet 色图
            colorbar(app.UIAxes_2)  % 在 UIAxes_2 上显示色彩条
            xlabel(app.UIAxes_2,'x (m)','FontSize', 12)
            ylabel(app.UIAxes_2,'y (m)','FontSize', 12)
            zlabel(app.UIAxes_2,'光强（cd）','FontSize', 12)
            title(app.UIAxes_2,'光强分布','FontSize', 12)
            % 设置坐标轴范围
            xlim(app.UIAxes_2,[app.xa, app.xb]);
            ylim(app.UIAxes_2,[app.ya, app.yb]);
            zlim(app.UIAxes_2,[0, max(app.I5(:))]);  % 设置 z 轴范围为 0 到光强的最大值
            x_ticks = app.xa:0.2:app.xb;
            y_ticks = app.ya:0.2:app.yb;
            % 设置X轴和Y轴的刻度
            xticks(app.UIAxes_2,x_ticks);
            yticks(app.UIAxes_2,y_ticks);
        end
               
        function saveImage(app, image)
            % 选择保存文件的位置和格式
            [file, path] = uiputfile({'*.jpg'; '*.png'; '*.bmp'}, 'Save Image As');
            % 如果用户未取消操作
            if ischar(file)
                % 获取完整的文件路径
                fullFileName = fullfile(path, file);
                % 将指定的 UIAxes 中的图像保存到指定路径
                exportgraphics(image, fullFileName);
                % 显示保存成功的消息
                uialert(app.UIFigure, '图像已成功保存！', '成功');
            else
                % 如果用户取消保存
                uialert(app.UIFigure, '操作已取消。', '提示');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 设置app界面居中
            Position_Screen = get(0,'ScreenSize');%获取屏幕尺寸
            Position_App = app.UIFigure.Position;
            Position_App(1) = (Position_Screen(3)-Position_App(3))/2;
            Position_App(2) = (Position_Screen(4)-Position_App(4))/2;
            app.UIFigure.Position = Position_App;
            % 设置初始编辑变量、初始文本
            DropDownValueChanged(app);
            app.TextArea.Editable = "off";
            % 初始绘图
            light_intensity2(app);
           
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            getvalue(app);
            if value == "双孔干涉（水平排列）"
                % 坐标
                app.mmEditField.Enable = "on";
                app.EditField.Enable = "off";
                app.xmEditField.Enable = "off";app.xmEditField.Value = 0;
                app.ymEditField.Enable = "off";app.ymEditField.Value = 0;
                app.xmEditField_2.Editable = "off";
                app.ymEditField_7.Editable = "off";
                app.xmEditField_3.Editable = "off";
                app.ymEditField_6.Editable = "off";
                app.xmEditField_4.Enable = "off";
                app.ymEditField_5.Enable = "off";
                app.xmEditField_5.Enable = "off";
                app.ymEditField_4.Enable = "off";
                app.xmEditField_6.Enable = "off";
                app.ymEditField_3.Enable = "off";
                app.xmEditField_7.Enable = "off";
                app.ymEditField_2.Enable = "off";

                % 文本
                app.Label_6.Text = ['光源(' '0' '，' '0' '，' num2str(app.zmEditField.Value) ')'];
                app.xmEditField_2.Value = app.m*1e6/2;
                app.ymEditField_7.Value = 0;
                app.xmEditField_3.Value = -app.m*1e6/2;
                app.ymEditField_6.Value = 0;
                app.Label_5.Text = ['(' num2str(app.xmEditField_2.Value, '%.3f') '，' num2str(app.ymEditField_7.Value, '%.3f') '，' '0' ')'];
                app.Label_7.Text = ['(' num2str(app.xmEditField_3.Value, '%.3f') '，' num2str(app.ymEditField_6.Value, '%.3f') '，' '0' ')'];
                app.NANLabel.Text = 'NAN';
                app.NANLabel_2.Text = 'NAN';
                app.NANLabel_3.Text = 'NAN';
                app.NANLabel_4.Text = 'NAN';

            elseif value == "三孔干涉（等边三角形排列）"
                % 坐标
                app.mmEditField.Enable = "on";
                app.EditField.Enable = "off";
                app.xmEditField.Enable = "off";app.xmEditField.Value = 0;
                app.ymEditField.Enable = "off";app.ymEditField.Value = 0;
                app.xmEditField_2.Editable = "off";
                app.ymEditField_7.Editable = "off";
                app.xmEditField_3.Editable = "off";
                app.ymEditField_6.Editable = "off";
                app.xmEditField_4.Editable = "off";app.xmEditField_4.Enable = "on";
                app.ymEditField_5.Editable = "off";app.ymEditField_5.Enable = "on";
                app.xmEditField_5.Enable = "off";
                app.ymEditField_4.Enable = "off";
                app.xmEditField_6.Enable = "off";
                app.ymEditField_3.Enable = "off";
                app.xmEditField_7.Enable = "off";
                app.ymEditField_2.Enable = "off";

                % 文本
                app.Label_6.Text = ['光源(' '0' '，' '0' '，' num2str(app.zmEditField.Value) ')'];
                app.xmEditField_2.Value = 0;
                app.ymEditField_7.Value = (sqrt(3)*app.m*1e6)/3;
                app.xmEditField_3.Value = app.m*1e6/2;
                app.ymEditField_6.Value = -(sqrt(3)*app.m*1e6)/6;
                app.xmEditField_4.Value = -app.m*1e6/2;
                app.ymEditField_5.Value = -(sqrt(3)*app.m*1e6)/6;
                app.Label_5.Text = ['(' num2str(app.xmEditField_2.Value, '%.3f') '，' num2str(app.ymEditField_7.Value, '%.3f') '，' '0' ')'];
                app.Label_7.Text = ['(' num2str(app.xmEditField_3.Value, '%.3f') '，' num2str(app.ymEditField_6.Value, '%.3f') '，' '0' ')'];                
                app.NANLabel.Text = ['(' num2str(app.xmEditField_4.Value, '%.3f') '，' num2str(app.ymEditField_5.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_2.Text = 'NAN';
                app.NANLabel_3.Text = 'NAN';
                app.NANLabel_4.Text = 'NAN';

            elseif value == "四孔干涉（水平排列）"
                % 坐标
                app.mmEditField.Enable = "on";
                app.EditField.Enable = "off";
                app.xmEditField.Enable = "off";app.xmEditField.Value = 0;
                app.ymEditField.Enable = "off";app.ymEditField.Value = 0;
                app.xmEditField_2.Editable = "off";
                app.ymEditField_7.Editable = "off";
                app.xmEditField_3.Editable = "off";
                app.ymEditField_6.Editable = "off";
                app.xmEditField_4.Editable = "off";app.xmEditField_4.Enable = "on";
                app.ymEditField_5.Editable = "off";app.ymEditField_5.Enable = "on";
                app.xmEditField_5.Editable = "off";app.xmEditField_5.Enable = "on";
                app.ymEditField_4.Editable = "off";app.ymEditField_4.Enable = "on";
                app.xmEditField_6.Enable = "off";
                app.ymEditField_3.Enable = "off";
                app.xmEditField_7.Enable = "off";
                app.ymEditField_2.Enable = "off";

                % 文本
                app.Label_6.Text = ['光源(' '0' '，' '0' '，' num2str(app.zmEditField.Value) ')'];
                app.xmEditField_2.Value = app.m*3*1e6/2;
                app.ymEditField_7.Value = 0;
                app.xmEditField_3.Value = app.m*1e6/2;
                app.ymEditField_6.Value = 0;
                app.xmEditField_4.Value = -app.m*1e6/2;
                app.ymEditField_5.Value = 0;
                app.xmEditField_5.Value = -app.m*3*1e6/2;
                app.ymEditField_4.Value = 0;
                app.Label_5.Text = ['(' num2str(app.xmEditField_2.Value, '%.3f') '，' num2str(app.ymEditField_7.Value, '%.3f') '，' '0' ')'];
                app.Label_7.Text = ['(' num2str(app.xmEditField_3.Value, '%.3f') '，' num2str(app.ymEditField_6.Value, '%.3f') '，' '0' ')'];                
                app.NANLabel.Text = ['(' num2str(app.xmEditField_4.Value, '%.3f') '，' num2str(app.ymEditField_5.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_2.Text = ['(' num2str(app.xmEditField_5.Value, '%.3f') '，' num2str(app.ymEditField_4.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_3.Text = 'NAN';
                app.NANLabel_4.Text = 'NAN';

            elseif value == "四孔干涉（正方形排列）"
                % 坐标
                app.mmEditField.Enable = "on";
                app.EditField.Enable = "off";
                app.xmEditField.Enable = "off";app.xmEditField.Value = 0;
                app.ymEditField.Enable = "off";app.ymEditField.Value = 0;
                app.xmEditField_2.Editable = "off";
                app.ymEditField_7.Editable = "off";
                app.xmEditField_3.Editable = "off";
                app.ymEditField_6.Editable = "off";
                app.xmEditField_4.Editable = "off";app.xmEditField_4.Enable = "on";
                app.ymEditField_5.Editable = "off";app.ymEditField_5.Enable = "on";
                app.xmEditField_5.Editable = "off";app.xmEditField_5.Enable = "on";
                app.ymEditField_4.Editable = "off";app.ymEditField_4.Enable = "on";
                app.xmEditField_6.Enable = "off";
                app.ymEditField_3.Enable = "off";
                app.xmEditField_7.Enable = "off";
                app.ymEditField_2.Enable = "off";

                % 文本
                app.Label_6.Text = ['光源(' '0' '，' '0' '，' num2str(app.zmEditField.Value) ')'];
                app.xmEditField_2.Value = app.m*1e6/2;
                app.ymEditField_7.Value = app.m*1e6/2;
                app.xmEditField_3.Value = app.m*1e6/2;
                app.ymEditField_6.Value = -app.m*1e6/2;
                app.xmEditField_4.Value = -app.m*1e6/2;
                app.ymEditField_5.Value = -app.m*1e6/2;
                app.xmEditField_5.Value = -app.m*1e6/2;
                app.ymEditField_4.Value = app.m*1e6/2;
                app.Label_5.Text = ['(' num2str(app.xmEditField_2.Value, '%.3f') '，' num2str(app.ymEditField_7.Value, '%.3f') '，' '0' ')'];
                app.Label_7.Text = ['(' num2str(app.xmEditField_3.Value, '%.3f') '，' num2str(app.ymEditField_6.Value, '%.3f') '，' '0' ')'];                
                app.NANLabel.Text = ['(' num2str(app.xmEditField_4.Value, '%.3f') '，' num2str(app.ymEditField_5.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_2.Text = ['(' num2str(app.xmEditField_5.Value, '%.3f') '，' num2str(app.ymEditField_4.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_3.Text = 'NAN';
                app.NANLabel_4.Text = 'NAN';

            else
                app.EditField.Enable = "on";
                EditFieldValueChanged(app);
            end
        end

        % Value changed function: mmEditField
        function mmEditFieldValueChanged(app, event)
            DropDownValueChanged(app);
            
        end

        % Value changed function: xmEditField
        function xmEditFieldValueChanged(app, event)

            app.Label_6.Text = ['光源(' num2str(app.xmEditField.Value) '，' num2str(app.ymEditField.Value) '，' num2str(app.zmEditField.Value) ')'];
            
        end

        % Value changed function: ymEditField
        function ymEditFieldValueChanged(app, event)

            app.Label_6.Text = ['光源(' num2str(app.xmEditField.Value) '，' num2str(app.ymEditField.Value) '，' num2str(app.zmEditField.Value) ')'];

        end

        % Value changed function: zmEditField
        function zmEditFieldValueChanged(app, event)
            app.Label_6.Text = ['光源(' num2str(app.xmEditField.Value) '，' num2str(app.ymEditField.Value) '，' num2str(app.zmEditField.Value) ')'];

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.DropDown.Value == "双孔干涉（水平排列）"
                light1(app);
            elseif app.DropDown.Value == "三孔干涉（等边三角形排列）"
                light2(app);
            elseif app.DropDown.Value == "四孔干涉（水平排列）"
                light3(app);
            elseif app.DropDown.Value == "四孔干涉（正方形排列）"
                light4(app);
            else 
                light5(app);
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if value == 2
                app.mmEditField.Enable = "off";
                app.xmEditField.Enable = "on";app.xmEditField.Editable = "on";
                app.ymEditField.Enable = "on";app.ymEditField.Editable = "on";
                app.xmEditField_2.Enable = "on";app.xmEditField_2.Editable = "on";
                app.ymEditField_7.Enable = "on";app.ymEditField_7.Editable = "on";
                app.xmEditField_3.Enable = "on";app.xmEditField_3.Editable = "on";
                app.ymEditField_6.Enable = "on";app.ymEditField_6.Editable = "on";
                app.xmEditField_4.Enable = "off";
                app.ymEditField_5.Enable = "off";
                app.xmEditField_5.Enable = "off";
                app.ymEditField_4.Enable = "off";
                app.xmEditField_6.Enable = "off";
                app.ymEditField_3.Enable = "off";
                app.xmEditField_7.Enable = "off";
                app.ymEditField_2.Enable = "off";

                app.Label_5.Text = ['(' num2str(app.xmEditField_2.Value, '%.3f') '，' num2str(app.ymEditField_7.Value, '%.3f') '，' '0' ')'];
                app.Label_7.Text = ['(' num2str(app.xmEditField_3.Value, '%.3f') '，' num2str(app.ymEditField_6.Value, '%.3f') '，' '0' ')'];
                app.NANLabel.Text = 'NAN';
                app.NANLabel_2.Text = 'NAN';
                app.NANLabel_3.Text = 'NAN';
                app.NANLabel_4.Text = 'NAN';

            elseif value == 3
                app.mmEditField.Enable = "off";
                app.xmEditField.Enable = "on";app.xmEditField.Editable = "on";
                app.ymEditField.Enable = "on";app.ymEditField.Editable = "on";
                app.xmEditField_2.Enable = "on";app.xmEditField_2.Editable = "on";
                app.ymEditField_7.Enable = "on";app.ymEditField_7.Editable = "on";
                app.xmEditField_3.Enable = "on";app.xmEditField_3.Editable = "on";
                app.ymEditField_6.Enable = "on";app.ymEditField_6.Editable = "on";
                app.xmEditField_4.Enable = "on";app.xmEditField_4.Editable = "on";
                app.ymEditField_5.Enable = "on";app.ymEditField_5.Editable = "on";
                app.xmEditField_5.Enable = "off";
                app.ymEditField_4.Enable = "off";
                app.xmEditField_6.Enable = "off";
                app.ymEditField_3.Enable = "off";
                app.xmEditField_7.Enable = "off";
                app.ymEditField_2.Enable = "off";

                app.Label_5.Text = ['(' num2str(app.xmEditField_2.Value, '%.3f') '，' num2str(app.ymEditField_7.Value, '%.3f') '，' '0' ')'];
                app.Label_7.Text = ['(' num2str(app.xmEditField_3.Value, '%.3f') '，' num2str(app.ymEditField_6.Value, '%.3f') '，' '0' ')'];                
                app.NANLabel.Text = ['(' num2str(app.xmEditField_4.Value, '%.3f') '，' num2str(app.ymEditField_5.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_2.Text = 'NAN';
                app.NANLabel_3.Text = 'NAN';
                app.NANLabel_4.Text = 'NAN';

            elseif value == 4
                app.mmEditField.Enable = "off";
                app.xmEditField.Enable = "on";app.xmEditField.Editable = "on";
                app.ymEditField.Enable = "on";app.ymEditField.Editable = "on";
                app.xmEditField_2.Enable = "on";app.xmEditField_2.Editable = "on";
                app.ymEditField_7.Enable = "on";app.ymEditField_7.Editable = "on";
                app.xmEditField_3.Enable = "on";app.xmEditField_3.Editable = "on";
                app.ymEditField_6.Enable = "on";app.ymEditField_6.Editable = "on";
                app.xmEditField_4.Enable = "on";app.xmEditField_4.Editable = "on";
                app.ymEditField_5.Enable = "on";app.ymEditField_5.Editable = "on";
                app.xmEditField_5.Enable = "on";app.xmEditField_5.Editable = "on";
                app.ymEditField_4.Enable = "on";app.ymEditField_4.Editable = "on";
                app.xmEditField_6.Enable = "off";
                app.ymEditField_3.Enable = "off";
                app.xmEditField_7.Enable = "off";
                app.ymEditField_2.Enable = "off";

                app.Label_5.Text = ['(' num2str(app.xmEditField_2.Value, '%.3f') '，' num2str(app.ymEditField_7.Value, '%.3f') '，' '0' ')'];
                app.Label_7.Text = ['(' num2str(app.xmEditField_3.Value, '%.3f') '，' num2str(app.ymEditField_6.Value, '%.3f') '，' '0' ')'];                
                app.NANLabel.Text = ['(' num2str(app.xmEditField_4.Value, '%.3f') '，' num2str(app.ymEditField_5.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_2.Text = ['(' num2str(app.xmEditField_5.Value, '%.3f') '，' num2str(app.ymEditField_4.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_3.Text = 'NAN';
                app.NANLabel_4.Text = 'NAN';

            elseif value == 5
                app.mmEditField.Enable = "off";
                app.xmEditField.Enable = "on";app.xmEditField.Editable = "on";
                app.ymEditField.Enable = "on";app.ymEditField.Editable = "on";
                app.xmEditField_2.Enable = "on";app.xmEditField_2.Editable = "on";
                app.ymEditField_7.Enable = "on";app.ymEditField_7.Editable = "on";
                app.xmEditField_3.Enable = "on";app.xmEditField_3.Editable = "on";
                app.ymEditField_6.Enable = "on";app.ymEditField_6.Editable = "on";
                app.xmEditField_4.Enable = "on";app.xmEditField_4.Editable = "on";
                app.ymEditField_5.Enable = "on";app.ymEditField_5.Editable = "on";
                app.xmEditField_5.Enable = "on";app.xmEditField_5.Editable = "on";
                app.ymEditField_4.Enable = "on";app.ymEditField_4.Editable = "on";
                app.xmEditField_6.Enable = "on";app.xmEditField_6.Editable = "on";
                app.ymEditField_3.Enable = "on";app.ymEditField_3.Editable = "on";
                app.xmEditField_7.Enable = "off";
                app.ymEditField_2.Enable = "off";

                app.Label_5.Text = ['(' num2str(app.xmEditField_2.Value, '%.3f') '，' num2str(app.ymEditField_7.Value, '%.3f') '，' '0' ')'];
                app.Label_7.Text = ['(' num2str(app.xmEditField_3.Value, '%.3f') '，' num2str(app.ymEditField_6.Value, '%.3f') '，' '0' ')'];                
                app.NANLabel.Text = ['(' num2str(app.xmEditField_4.Value, '%.3f') '，' num2str(app.ymEditField_5.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_2.Text = ['(' num2str(app.xmEditField_5.Value, '%.3f') '，' num2str(app.ymEditField_4.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_3.Text = ['(' num2str(app.xmEditField_6.Value, '%.3f') '，' num2str(app.ymEditField_3.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_4.Text = 'NAN';

            else   
                app.mmEditField.Enable = "off";
                app.xmEditField.Enable = "on";app.xmEditField.Editable = "on";
                app.ymEditField.Enable = "on";app.ymEditField.Editable = "on";
                app.xmEditField_2.Enable = "on";app.xmEditField_2.Editable = "on";
                app.ymEditField_7.Enable = "on";app.ymEditField_7.Editable = "on";
                app.xmEditField_3.Enable = "on";app.xmEditField_3.Editable = "on";
                app.ymEditField_6.Enable = "on";app.ymEditField_6.Editable = "on";
                app.xmEditField_4.Enable = "on";app.xmEditField_4.Editable = "on";
                app.ymEditField_5.Enable = "on";app.ymEditField_5.Editable = "on";
                app.xmEditField_5.Enable = "on";app.xmEditField_5.Editable = "on";
                app.ymEditField_4.Enable = "on";app.ymEditField_4.Editable = "on";
                app.xmEditField_6.Enable = "on";app.xmEditField_6.Editable = "on";
                app.ymEditField_3.Enable = "on";app.ymEditField_3.Editable = "on";
                app.xmEditField_7.Enable = "on";app.xmEditField_7.Editable = "on";
                app.ymEditField_2.Enable = "on";app.ymEditField_2.Editable = "on";

                app.Label_5.Text = ['(' num2str(app.xmEditField_2.Value, '%.3f') '，' num2str(app.ymEditField_7.Value, '%.3f') '，' '0' ')'];
                app.Label_7.Text = ['(' num2str(app.xmEditField_3.Value, '%.3f') '，' num2str(app.ymEditField_6.Value, '%.3f') '，' '0' ')'];                
                app.NANLabel.Text = ['(' num2str(app.xmEditField_4.Value, '%.3f') '，' num2str(app.ymEditField_5.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_2.Text = ['(' num2str(app.xmEditField_5.Value, '%.3f') '，' num2str(app.ymEditField_4.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_3.Text = ['(' num2str(app.xmEditField_6.Value, '%.3f') '，' num2str(app.ymEditField_3.Value, '%.3f') '，' '0' ')'];
                app.NANLabel_4.Text = ['(' num2str(app.xmEditField_7.Value, '%.3f') '，' num2str(app.ymEditField_2.Value, '%.3f') '，' '0' ')'];
            end

        end

        % Value changed function: xmEditField_2
        function xmEditField_2ValueChanged(app, event)
            app.Label_5.Text = ['(' num2str(app.xmEditField_2.Value, '%.3f') '，' num2str(app.ymEditField_7.Value, '%.3f') '，' '0' ')'];
            
        end

        % Value changed function: ymEditField_7
        function ymEditField_7ValueChanged(app, event)
                app.Label_5.Text = ['(' num2str(app.xmEditField_2.Value, '%.3f') '，' num2str(app.ymEditField_7.Value, '%.3f') '，' '0' ')'];
        end

        % Value changed function: xmEditField_3
        function xmEditField_3ValueChanged(app, event)

            app.Label_7.Text = ['(' num2str(app.xmEditField_3.Value, '%.3f') '，' num2str(app.ymEditField_6.Value, '%.3f') '，' '0' ')'];                
        end

        % Value changed function: ymEditField_6
        function ymEditField_6ValueChanged(app, event)
            app.Label_7.Text = ['(' num2str(app.xmEditField_3.Value, '%.3f') '，' num2str(app.ymEditField_6.Value, '%.3f') '，' '0' ')'];                
            
        end

        % Value changed function: xmEditField_4
        function xmEditField_4ValueChanged(app, event)
               app.NANLabel.Text = ['(' num2str(app.xmEditField_4.Value, '%.3f') '，' num2str(app.ymEditField_5.Value, '%.3f') '，' '0' ')'];
            
        end

        % Value changed function: ymEditField_5
        function ymEditField_5ValueChanged(app, event)
             app.NANLabel.Text = ['(' num2str(app.xmEditField_4.Value, '%.3f') '，' num2str(app.ymEditField_5.Value, '%.3f') '，' '0' ')'];
            
        end

        % Value changed function: xmEditField_5
        function xmEditField_5ValueChanged(app, event)
            app.NANLabel_2.Text = ['(' num2str(app.xmEditField_5.Value, '%.3f') '，' num2str(app.ymEditField_4.Value, '%.3f') '，' '0' ')'];
            
        end

        % Value changed function: ymEditField_4
        function ymEditField_4ValueChanged(app, event)
            app.NANLabel_2.Text = ['(' num2str(app.xmEditField_5.Value, '%.3f') '，' num2str(app.ymEditField_4.Value, '%.3f') '，' '0' ')'];
            
        end

        % Value changed function: xmEditField_6
        function xmEditField_6ValueChanged(app, event)
            app.NANLabel_3.Text = ['(' num2str(app.xmEditField_6.Value, '%.3f') '，' num2str(app.ymEditField_3.Value, '%.3f') '，' '0' ')'];
            
        end

        % Value changed function: ymEditField_3
        function ymEditField_3ValueChanged(app, event)
            app.NANLabel_3.Text = ['(' num2str(app.xmEditField_6.Value, '%.3f') '，' num2str(app.ymEditField_3.Value, '%.3f') '，' '0' ')'];
            
        end

        % Value changed function: xmEditField_7
        function xmEditField_7ValueChanged(app, event)
            app.NANLabel_4.Text = ['(' num2str(app.xmEditField_7.Value, '%.3f') '，' num2str(app.ymEditField_2.Value, '%.3f') '，' '0' ')'];
            
        end

        % Value changed function: ymEditField_2
        function ymEditField_2ValueChanged(app, event)
           app.NANLabel_4.Text = ['(' num2str(app.xmEditField_7.Value, '%.3f') '，' num2str(app.ymEditField_2.Value, '%.3f') '，' '0' ')'];
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.DropDown.Value == "双孔干涉（水平排列）"
                light_intensity1(app);
            elseif app.DropDown.Value == "三孔干涉（等边三角形排列）"
                light_intensity2(app);
            elseif app.DropDown.Value == "四孔干涉（水平排列）"
                light_intensity3(app);
            elseif app.DropDown.Value == "四孔干涉（正方形排列）"
                light_intensity4(app);
            else 
                light_intensity5(app);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            cla(app.UIAxes_2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            saveImage(app, app.UIAxes);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            saveImage(app, app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '实验简介';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '多孔干涉仿真实验原理介绍';
            app.TextArea = uitextarea(app.Panel_2);
            app.TextArea.Value = {'       在光学实验中，当光通过数个对称排列的小孔时，各孔发出的光波相互叠加，形成独特的干涉图案。由于小孔的对称分布，干涉条纹也呈现对称特性。在屏幕上，可以看到明暗相间的条纹，这些条纹的间距和亮度受到光的波长、小孔间距及光源条件等因素的影响。对称多孔干涉不仅展示了光的波动性质，还在光学测量和光学信息处理等领域具有应用潜力。本仿真实验可通过调控单色光波长λ、观察屏到小孔平面的距离D，小孔两两之间的距离m，光源与小孔平面的垂直距离L等参数，探究两孔干涉（水平排列）、三孔干涉（等边三角形排列）、四孔干涉（水平排列和正方形排列）等对称排列情况下的干涉条纹以及光强分布情况。'; '       本实验还可以选择自定义模式下的多孔干涉实验，通过自定义小孔坐标（最大6个，最小2个）来探究各种规则或不规则排布下的多孔干涉情形，发掘不同干涉现象的规律。同时还可调节光源坐标和光源振幅，设置不同小孔的初相位使实验真实模拟现实条件下的各种情况，实现光场的精确操控与测量。'; ''};
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, '5.png');
            app.Image2 = uiimage(app.Panel_2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '7.png');
            app.Image3 = uiimage(app.Panel_2);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '6.png');
            app.Label_9 = uilabel(app.Panel_2);
            app.Label_9.Text = '三孔干涉示意图';
            app.Label_10 = uilabel(app.Panel_2);
            app.Label_10.Text = '四孔干涉示意图(正方形对称排布)';
            app.Label_11 = uilabel(app.Panel_2);
            app.Label_11.Text = '四孔干涉示意图（水平排布）';
            app.nLabel = uilabel(app.Panel_2);
            app.nLabel.Text = '对于n个小孔干涉光强公式：';
            app.Image4 = uiimage(app.Panel_2);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '8.png');
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '多孔干涉仿真实验';
            app.UIAxes = uiaxes(app.Tab_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Tab_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel = uipanel(app.Tab_2);
            app.Panel.Tooltip = {'输入值必须为正数'};
            app.Panel.Title = '实验参数设定';
            app.nmEditFieldLabel = uilabel(app.Panel);
            app.nmEditFieldLabel.Text = '波长λ（nm）';
            app.nmEditField = uieditfield(app.Panel, 'numeric');
            app.nmEditField.Limits = [0 Inf];
            app.nmEditField.Tooltip = {'输入值必须为正数'};
            app.nmEditField.Value = 700;
            app.DmLabel = uilabel(app.Panel);
            app.DmLabel.Text = '观察屏与小孔所在平面的垂直距离D（m）';
            app.DmEditField = uieditfield(app.Panel, 'numeric');
            app.DmEditField.Limits = [0 Inf];
            app.DmEditField.Tooltip = {'输入值必须为正数'};
            app.DmEditField.Value = 20;
            app.xamSpinnerLabel = uilabel(app.Panel);
            app.xamSpinnerLabel.Text = '最小横坐标xa（m）';
            app.xamSpinner = uispinner(app.Panel);
            app.xamSpinner.Step = 0.5;
            app.xamSpinner.Value = -0.5;
            app.yamSpinnerLabel = uilabel(app.Panel);
            app.yamSpinnerLabel.Text = '最小纵坐标ya（m）';
            app.yamSpinner = uispinner(app.Panel);
            app.yamSpinner.Step = 0.5;
            app.yamSpinner.Value = -0.5;
            app.xbmSpinnerLabel = uilabel(app.Panel);
            app.xbmSpinnerLabel.Text = '最大横坐标xb（m）';
            app.xbmSpinner = uispinner(app.Panel);
            app.xbmSpinner.Step = 0.5;
            app.xbmSpinner.Value = 0.5;
            app.ybmSpinnerLabel = uilabel(app.Panel);
            app.ybmSpinnerLabel.Text = '最大纵坐标yb（m）';
            app.ybmSpinner = uispinner(app.Panel);
            app.ybmSpinner.Step = 0.5;
            app.ybmSpinner.Value = 0.5;
            app.xN1SpinnerLabel = uilabel(app.Panel);
            app.xN1SpinnerLabel.Text = 'x轴方向等分份数N1';
            app.xN1Spinner = uispinner(app.Panel);
            app.xN1Spinner.Step = 100;
            app.xN1Spinner.Limits = [0 Inf];
            app.xN1Spinner.Value = 1000;
            app.yN2SpinnerLabel = uilabel(app.Panel);
            app.yN2SpinnerLabel.Text = 'y轴方向等分份数N2';
            app.yN2Spinner = uispinner(app.Panel);
            app.yN2Spinner.Step = 100;
            app.yN2Spinner.Limits = [0 Inf];
            app.yN2Spinner.Value = 1000;
            app.Label = uilabel(app.Panel);
            app.Label.Text = '选择干涉类型';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'双孔干涉（水平排列）', '三孔干涉（等边三角形排列）', '四孔干涉（水平排列）', '四孔干涉（正方形排列）', '自定义多孔干涉'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '三孔干涉（等边三角形排列）';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '光源振幅A0';
            app.A0Slider = uislider(app.Panel);
            app.A0Slider.Limits = [1 10];
            app.A0Slider.Value = 1;
            app.mmLabel = uilabel(app.Panel);
            app.mmLabel.Text = '非自定义情况下小孔两两之间的距离m（μm）';
            app.mmEditField = uieditfield(app.Panel, 'numeric');
            app.mmEditField.Limits = [0 Inf];
            app.mmEditField.ValueChangedFcn = createCallbackFcn(app, @mmEditFieldValueChanged, true);
            app.mmEditField.Tooltip = {'输入值必须为正数'};
            app.mmEditField.Value = 0.2;
            app.xmEditFieldLabel = uilabel(app.Panel);
            app.xmEditFieldLabel.Text = '光源x坐标（m）';
            app.xmEditField = uieditfield(app.Panel, 'numeric');
            app.xmEditField.ValueChangedFcn = createCallbackFcn(app, @xmEditFieldValueChanged, true);
            app.xmEditField.Tooltip = {''};
            app.ymEditFieldLabel = uilabel(app.Panel);
            app.ymEditFieldLabel.Text = '光源y坐标（m）';
            app.ymEditField = uieditfield(app.Panel, 'numeric');
            app.ymEditField.ValueChangedFcn = createCallbackFcn(app, @ymEditFieldValueChanged, true);
            app.ymEditField.Tooltip = {''};
            app.zmLabel = uilabel(app.Panel);
            app.zmLabel.Text = '光源z坐标（光源与小孔平面的垂直距离）（m）';
            app.zmEditField = uieditfield(app.Panel, 'numeric');
            app.zmEditField.Limits = [-Inf 0];
            app.zmEditField.ValueChangedFcn = createCallbackFcn(app, @zmEditFieldValueChanged, true);
            app.zmEditField.Tooltip = {'输入值必须小于0'};
            app.zmEditField.Value = -1;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '设定光源坐标：';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '自定义小孔个数(最小为2个，最大为6个) ';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Limits = [2 6];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {'输入2-6之间的整数'};
            app.EditField.Value = 2;
            app.xmLabel = uilabel(app.Panel);
            app.xmLabel.Text = '小孔1x坐标（μm）';
            app.xmEditField_2 = uieditfield(app.Panel, 'numeric');
            app.xmEditField_2.ValueChangedFcn = createCallbackFcn(app, @xmEditField_2ValueChanged, true);
            app.xmEditField_2.Tooltip = {''};
            app.ymEditField_7Label = uilabel(app.Panel);
            app.ymEditField_7Label.Text = '小孔1y坐标（μm）';
            app.ymEditField_7 = uieditfield(app.Panel, 'numeric');
            app.ymEditField_7.ValueChangedFcn = createCallbackFcn(app, @ymEditField_7ValueChanged, true);
            app.ymEditField_7.Tooltip = {''};
            app.xmLabel_2 = uilabel(app.Panel);
            app.xmLabel_2.Text = '小孔2x坐标（μm）';
            app.xmEditField_3 = uieditfield(app.Panel, 'numeric');
            app.xmEditField_3.ValueChangedFcn = createCallbackFcn(app, @xmEditField_3ValueChanged, true);
            app.xmEditField_3.Tooltip = {''};
            app.xmLabel_3 = uilabel(app.Panel);
            app.xmLabel_3.Text = '小孔2y坐标（μm）';
            app.ymEditField_6 = uieditfield(app.Panel, 'numeric');
            app.ymEditField_6.ValueChangedFcn = createCallbackFcn(app, @ymEditField_6ValueChanged, true);
            app.ymEditField_6.Tooltip = {''};
            app.xmLabel_4 = uilabel(app.Panel);
            app.xmLabel_4.Text = '小孔3x坐标（μm）';
            app.xmEditField_4 = uieditfield(app.Panel, 'numeric');
            app.xmEditField_4.ValueChangedFcn = createCallbackFcn(app, @xmEditField_4ValueChanged, true);
            app.xmEditField_4.Tooltip = {''};
            app.xmLabel_5 = uilabel(app.Panel);
            app.xmLabel_5.Text = '小孔3y坐标（μm）';
            app.ymEditField_5 = uieditfield(app.Panel, 'numeric');
            app.ymEditField_5.ValueChangedFcn = createCallbackFcn(app, @ymEditField_5ValueChanged, true);
            app.ymEditField_5.Tooltip = {''};
            app.xmLabel_6 = uilabel(app.Panel);
            app.xmLabel_6.Text = '小孔4x坐标（μm）';
            app.xmEditField_5 = uieditfield(app.Panel, 'numeric');
            app.xmEditField_5.ValueChangedFcn = createCallbackFcn(app, @xmEditField_5ValueChanged, true);
            app.xmEditField_5.Tooltip = {''};
            app.ymEditField_4Label = uilabel(app.Panel);
            app.ymEditField_4Label.Text = '小孔4y坐标（μm）';
            app.ymEditField_4 = uieditfield(app.Panel, 'numeric');
            app.ymEditField_4.ValueChangedFcn = createCallbackFcn(app, @ymEditField_4ValueChanged, true);
            app.ymEditField_4.Tooltip = {''};
            app.xmLabel_7 = uilabel(app.Panel);
            app.xmLabel_7.Text = '小孔5x坐标（μm）';
            app.xmEditField_6 = uieditfield(app.Panel, 'numeric');
            app.xmEditField_6.ValueChangedFcn = createCallbackFcn(app, @xmEditField_6ValueChanged, true);
            app.xmEditField_6.Tooltip = {''};
            app.ymLabel = uilabel(app.Panel);
            app.ymLabel.Text = '小孔5y坐标（μm）';
            app.ymEditField_3 = uieditfield(app.Panel, 'numeric');
            app.ymEditField_3.ValueChangedFcn = createCallbackFcn(app, @ymEditField_3ValueChanged, true);
            app.ymEditField_3.Tooltip = {''};
            app.xmLabel_8 = uilabel(app.Panel);
            app.xmLabel_8.Text = '小孔6x坐标（μm）';
            app.xmEditField_7 = uieditfield(app.Panel, 'numeric');
            app.xmEditField_7.ValueChangedFcn = createCallbackFcn(app, @xmEditField_7ValueChanged, true);
            app.xmEditField_7.Tooltip = {''};
            app.ymEditField_2Label = uilabel(app.Panel);
            app.ymEditField_2Label.Text = '小孔6y坐标（μm）';
            app.ymEditField_2 = uieditfield(app.Panel, 'numeric');
            app.ymEditField_2.ValueChangedFcn = createCallbackFcn(app, @ymEditField_2ValueChanged, true);
            app.ymEditField_2.Tooltip = {''};
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '小孔1(0 , 0 , 0)';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '光源(0 , 0 , -1)';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '小孔2(0 , 0 , 0)';
            app.NANLabel = uilabel(app.Panel);
            app.NANLabel.Text = 'NAN';
            app.NANLabel_2 = uilabel(app.Panel);
            app.NANLabel_2.Text = 'NAN';
            app.NANLabel_3 = uilabel(app.Panel);
            app.NANLabel_3.Text = 'NAN';
            app.NANLabel_4 = uilabel(app.Panel);
            app.NANLabel_4.Text = 'NAN';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '图像观察范围设置';
            app.Button = uibutton(app.Tab_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '绘制干涉条纹图像';
            app.Button_2 = uibutton(app.Tab_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '清除坐标系';
            app.Button_3 = uibutton(app.Tab_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '图像另存为';
            app.Button_4 = uibutton(app.Tab_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '绘制光强分布图像';
            app.Button_5 = uibutton(app.Tab_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '清除坐标系';
            app.Button_6 = uibutton(app.Tab_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '图像另存为';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ganshe2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end