classdef total_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        MATLABLabel  matlab.ui.control.Label
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function Close(app, name)
            app.UIFigure.Visible = 'on';
            delete(name);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 设置app界面居中
            Position_Screen = get(0,'ScreenSize');%获取屏幕尺寸
            Position_App = app.UIFigure.Position;
            Position_App(1) = (Position_Screen(3)-Position_App(3))/2;
            Position_App(2) = (Position_Screen(4)-Position_App(4))/2;
            app.UIFigure.Position = Position_App;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            exp1 = ganshe1();  % 创建实验1的App
            exp1.gansheUIFigure.Visible = 'on';  % 显示实验1的界面
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            exp2 = ganshe2();  % 创建实验2的App
            exp2.UIFigure.Visible = 'on';  % 显示实验2的界面
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '杨氏双缝干涉实验';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '多孔干涉实验';
            app.MATLABLabel = uilabel(app.UIFigure);
            app.MATLABLabel.Text = {'基于 MATLAB 的双缝干涉和'; '多孔干涉仿真实验'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = total_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end